classdef Link_Budget < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GeostationarySatelliteLinkBudgetToolPanel  matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        InputTab                        matlab.ui.container.Tab
        AntennaApertureEfficiencyEditField  matlab.ui.control.EditField
        AntennaApertureEfficiencyEditFieldLabel  matlab.ui.control.Label
        PointingLossdBEditField         matlab.ui.control.EditField
        PointingLossdBEditFieldLabel    matlab.ui.control.Label
        AtmosphericLossdBEditField      matlab.ui.control.EditField
        AtmosphericLossdBEditFieldLabel  matlab.ui.control.Label
        RainAttenuationPanel            matlab.ui.container.Panel
        LinkRainAvailabilityEditField   matlab.ui.control.NumericEditField
        LinkRainAvailabilityEditFieldLabel  matlab.ui.control.Label
        EnableCheckBox                  matlab.ui.control.CheckBox
        ndVsatParametersPanel           matlab.ui.container.Panel
        VsatPowerWEditField_2           matlab.ui.control.EditField
        PowerWEditFieldLabel_4          matlab.ui.control.Label
        VsatAntennaDiametermEditField_2  matlab.ui.control.EditField
        AntennaDiametermEditField_2Label_2  matlab.ui.control.Label
        VsatLongitudeEditField_2        matlab.ui.control.EditField
        LongitudeEditFieldLabel_4       matlab.ui.control.Label
        VsatLatitudeEditField_2         matlab.ui.control.EditField
        LatitudeEditFieldLabel_3        matlab.ui.control.Label
        VsatLocEditField_2              matlab.ui.control.EditField
        LocationLabel_3                 matlab.ui.control.Label
        VsatSearchButton_2              matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
        TransmissionParametersPanel     matlab.ui.container.Panel
        SatelliteHPACompressiondBEditField  matlab.ui.control.EditField
        SatelliteHPACompressiondBEditFieldLabel  matlab.ui.control.Label
        DownlinkFreq                    matlab.ui.control.EditField
        UplinkFreq                      matlab.ui.control.EditField
        AccessSchemeDropDown            matlab.ui.control.DropDown
        AccessSchemeDropDownLabel       matlab.ui.control.Label
        DownlinkFrequencyGHzSlider      matlab.ui.control.Slider
        DownlinkFrequencyGHzLabel       matlab.ui.control.Label
        UplinkFrequencyGHzSlider        matlab.ui.control.Slider
        UplinkFrequencyGHzLabel         matlab.ui.control.Label
        SatelliteParametersPanel        matlab.ui.container.Panel
        SatGTEditField                  matlab.ui.control.EditField
        GTdBKLabel_4                    matlab.ui.control.Label
        CheckBox                        matlab.ui.control.CheckBox
        SatEIRPdBEditField              matlab.ui.control.EditField
        EIRPdBLabel_4                   matlab.ui.control.Label
        FeederLossdBEditField           matlab.ui.control.EditField
        FeederLossdBLabel               matlab.ui.control.Label
        LNBTempKEditField               matlab.ui.control.EditField
        NoiseTempKLabel_2               matlab.ui.control.Label
        SatAntennaDiametermEditField    matlab.ui.control.EditField
        AntennaDiametermEditFieldLabel_4  matlab.ui.control.Label
        TransponderBandwithMHzEditField  matlab.ui.control.EditField
        TransponderBandwithMHzEditFieldLabel  matlab.ui.control.Label
        ChannelBandwithMHzEditField     matlab.ui.control.EditField
        ChannelBandwithMHzEditFieldLabel  matlab.ui.control.Label
        SatPowerWEditField              matlab.ui.control.EditField
        PowerWEditFieldLabel            matlab.ui.control.Label
        SatGaindBEditField              matlab.ui.control.EditField
        GaindBiLabel                    matlab.ui.control.Label
        SatTempKEditField               matlab.ui.control.EditField
        NoiseTempKLabel                 matlab.ui.control.Label
        BandDropDown                    matlab.ui.control.DropDown
        BandDropDownLabel               matlab.ui.control.Label
        SatLongitudeEditField           matlab.ui.control.EditField
        LongitudeEditFieldLabel_3       matlab.ui.control.Label
        SatNameDropDown                 matlab.ui.control.DropDown
        NameDropDownLabel               matlab.ui.control.Label
        SatDateEditField                matlab.ui.control.EditField
        DateEditFieldLabel              matlab.ui.control.Label
        stVsatParametersPanel           matlab.ui.container.Panel
        VsatPowerWEditField             matlab.ui.control.EditField
        PowerWEditFieldLabel_3          matlab.ui.control.Label
        VsatAntennaDiametermEditField   matlab.ui.control.EditField
        AntennaDiametermEditField_2Label  matlab.ui.control.Label
        VsatLongitudeEditField          matlab.ui.control.EditField
        LongitudeEditFieldLabel_2       matlab.ui.control.Label
        VsatLatitudeEditField           matlab.ui.control.EditField
        LatitudeEditFieldLabel_2        matlab.ui.control.Label
        VsatLocEditField                matlab.ui.control.EditField
        LocationLabel_2                 matlab.ui.control.Label
        VsatSearchButton                matlab.ui.control.Button
        HubParametersPanel              matlab.ui.container.Panel
        HubPowerWEditField              matlab.ui.control.EditField
        PowerWEditFieldLabel_2          matlab.ui.control.Label
        HubAntennaDiametermEditField    matlab.ui.control.EditField
        AntennaDiametermEditFieldLabel_3  matlab.ui.control.Label
        HubLongitudeEditField           matlab.ui.control.EditField
        LongitudeEditFieldLabel         matlab.ui.control.Label
        HubLatitudeEditField            matlab.ui.control.EditField
        LatitudeEditFieldLabel          matlab.ui.control.Label
        HubSearchButton                 matlab.ui.control.Button
        HubLocEditField                 matlab.ui.control.EditField
        LocationLabel                   matlab.ui.control.Label
        CalculationsTab                 matlab.ui.container.Tab
        SatellitePanel                  matlab.ui.container.Panel
        Lamp                            matlab.ui.control.Lamp
        SatellitePFDdBWm2EditField      matlab.ui.control.EditField
        SatellitePFDdBWm2EditField_2Label  matlab.ui.control.Label
        SatelliteSFDdBWm2EditField      matlab.ui.control.EditField
        SatelliteSFDdBWm2Label          matlab.ui.control.Label
        SatelliteEIRPdBEditField        matlab.ui.control.EditField
        SatelliteEIRPdBEditFieldLabel   matlab.ui.control.Label
        SatelliteGTdBKEditField         matlab.ui.control.EditField
        SatelliteGTdBKEditFieldLabel    matlab.ui.control.Label
        ndVsatPanel                     matlab.ui.container.Panel
        VsatGTEditField_2               matlab.ui.control.EditField
        GTdBKLabel_3                    matlab.ui.control.Label
        VsatPFDEditField_2              matlab.ui.control.EditField
        SFDdBWm2Label_6                 matlab.ui.control.Label
        VsatEIRPdBEditField_2           matlab.ui.control.EditField
        EIRPdBLabel_3                   matlab.ui.control.Label
        VsatSFDEditField_2              matlab.ui.control.EditField
        SFDdBWm2Label_5                 matlab.ui.control.Label
        VsatGaindBiEditField_2          matlab.ui.control.EditField
        GaindBiEditFieldLabel_3         matlab.ui.control.Label
        VsatSatelliteVisibilityLamp_2   matlab.ui.control.Lamp
        SatelliteVisibilityLabel_3      matlab.ui.control.Label
        VsatFreeSpaceLossdBEditField_2  matlab.ui.control.EditField
        FreeSpaceLossdBEditFieldLabel_3  matlab.ui.control.Label
        VsatSlantRangeKmEditField_2     matlab.ui.control.EditField
        SlantRangeKmEditFieldLabel_3    matlab.ui.control.Label
        VsatAzimuthAngledegEditField_2  matlab.ui.control.EditField
        AzimuthAngledegEditFieldLabel_3  matlab.ui.control.Label
        VsatElevationAngledegEditField_2  matlab.ui.control.EditField
        ElevationAngledegEditFieldLabel_3  matlab.ui.control.Label
        stVsatPanel                     matlab.ui.container.Panel
        VsatGTEditField                 matlab.ui.control.EditField
        GTdBKLabel_2                    matlab.ui.control.Label
        VsatPFDEditField                matlab.ui.control.EditField
        SFDdBWm2Label_4                 matlab.ui.control.Label
        VsatEIRPdBEditField             matlab.ui.control.EditField
        EIRPdBLabel_2                   matlab.ui.control.Label
        VsatSFDEditField                matlab.ui.control.EditField
        SFDdBWm2Label_2                 matlab.ui.control.Label
        VsatGaindBiEditField            matlab.ui.control.EditField
        GaindBiEditFieldLabel_2         matlab.ui.control.Label
        VsatSatelliteVisibilityLamp     matlab.ui.control.Lamp
        SatelliteVisibilityLabel_2      matlab.ui.control.Label
        VsatFreeSpaceLossdBEditField    matlab.ui.control.EditField
        FreeSpaceLossdBEditFieldLabel_2  matlab.ui.control.Label
        VsatSlantRangeKmEditField       matlab.ui.control.EditField
        SlantRangeKmEditFieldLabel_2    matlab.ui.control.Label
        VsatAzimuthAngledegEditField    matlab.ui.control.EditField
        AzimuthAngledegEditFieldLabel_2  matlab.ui.control.Label
        VsatElevationAngledegEditField  matlab.ui.control.EditField
        ElevationAngledegEditFieldLabel_2  matlab.ui.control.Label
        HubPanel                        matlab.ui.container.Panel
        HubGTEditField                  matlab.ui.control.EditField
        GTdBKLabel                      matlab.ui.control.Label
        HubPFDEditField                 matlab.ui.control.EditField
        SFDdBWm2Label_3                 matlab.ui.control.Label
        HubEIRPdBEditField              matlab.ui.control.EditField
        EIRPdBLabel                     matlab.ui.control.Label
        HubSFDEditField                 matlab.ui.control.EditField
        SFDdBWm2Label                   matlab.ui.control.Label
        HubGaindBiEditField             matlab.ui.control.EditField
        GaindBiEditFieldLabel           matlab.ui.control.Label
        HubSatelliteVisibilityLamp      matlab.ui.control.Lamp
        SatelliteVisibilityLabel        matlab.ui.control.Label
        HubFreeSpaceLossdBEditField     matlab.ui.control.EditField
        FreeSpaceLossdBEditFieldLabel   matlab.ui.control.Label
        HubSlantRangeKmEditField        matlab.ui.control.EditField
        SlantRangeKmEditFieldLabel      matlab.ui.control.Label
        HubAzimuthAngledegEditField     matlab.ui.control.EditField
        AzimuthAngledegEditFieldLabel   matlab.ui.control.Label
        HubElevationAngledegEditField   matlab.ui.control.EditField
        ElevationAngledegEditFieldLabel  matlab.ui.control.Label
        LinkBudgetTab                   matlab.ui.container.Tab
        LinkBudgetPanel                 matlab.ui.container.Panel
        RainFadeAttenuationPanel        matlab.ui.container.Panel
        ndVsatDownlinkAttenuationdBEditField  matlab.ui.control.EditField
        ndVsatDownlinkAttenuationdBEditFieldLabel  matlab.ui.control.Label
        ndVsatUplinkAttenuationdBEditField  matlab.ui.control.EditField
        ndVsatUplinkAttenuationdBEditFieldLabel  matlab.ui.control.Label
        stVsatDownlinkAttenuationdBEditField  matlab.ui.control.EditField
        stVsatDownlinkAttenuationdBEditFieldLabel  matlab.ui.control.Label
        stVsatUplinkAttenuationdBEditField  matlab.ui.control.EditField
        stVsatUplinkAttenuationdBEditFieldLabel  matlab.ui.control.Label
        HubDownlinkAttenuationdBEditField  matlab.ui.control.EditField
        HubDownlinkAttenuationdBEditFieldLabel  matlab.ui.control.Label
        HubUplinkAttenuationdBEditField  matlab.ui.control.EditField
        HubUplinkAttenuationdBEditFieldLabel  matlab.ui.control.Label
        ChipRateMchipssDropDown         matlab.ui.control.DropDown
        ChipRateMchipssDropDownLabel    matlab.ui.control.Label
        CodeRateDropDown                matlab.ui.control.DropDown
        CodeRateDropDownLabel           matlab.ui.control.Label
        ModulationDropDown              matlab.ui.control.DropDown
        ModulationDropDownLabel         matlab.ui.control.Label
        ComplexSNRdBEditField           matlab.ui.control.EditField
        ComplexSNRdBEditField_2Label    matlab.ui.control.Label
        ComplexLinkMargindBEditField    matlab.ui.control.EditField
        ComplexLinkMargindBEditFieldLabel  matlab.ui.control.Label
        AvailableEbN0dBEditField        matlab.ui.control.EditField
        AvailableEbN0dBEditFieldLabel   matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        NetworkConfigurationLabel       matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        HubUplinkRTNSNRdBEditField      matlab.ui.control.EditField
        HubUplinkRTNSNRdBEditFieldLabel  matlab.ui.control.Label
        HubUplinkFWDSNRdBEditField      matlab.ui.control.EditField
        HubUplinkFWDSNRdBEditFieldLabel  matlab.ui.control.Label
        HubDownlinkRTNSNRdBEditField    matlab.ui.control.EditField
        HubDownlinkRTNSNRdBEditFieldLabel  matlab.ui.control.Label
        HubDownlinkFWDSNRdBEditField    matlab.ui.control.EditField
        HubDownlinkFWDSNRdBLabel        matlab.ui.control.Label
        RTNLamp                         matlab.ui.control.Lamp
        FWDLamp                         matlab.ui.control.Lamp
        RTNLinkMargindBEditField        matlab.ui.control.EditField
        RTNLinkMargindBEditFieldLabel   matlab.ui.control.Label
        FWDLinkMargindBEditField        matlab.ui.control.EditField
        FWDLinkMargindBEditFieldLabel   matlab.ui.control.Label
        RequiredEbN0dBEditField         matlab.ui.control.EditField
        RequiredEbN0dBEditFieldLabel    matlab.ui.control.Label
        SpreadingDropDown               matlab.ui.control.DropDown
        SpreadingDropDownLabel          matlab.ui.control.Label
        AvailableRTNEbN0dBEditField     matlab.ui.control.EditField
        AvailableRTNEbN0dBEditFieldLabel  matlab.ui.control.Label
        AvailableFWDEbN0dBEditField     matlab.ui.control.EditField
        AvailableFWDEbN0dBEditFieldLabel  matlab.ui.control.Label
        RTNBitrateMbpsEditField         matlab.ui.control.EditField
        RTNBitrateMbpsEditFieldLabel    matlab.ui.control.Label
        FWDBitrateMbpsEditField         matlab.ui.control.EditField
        FWDBitrateMbpsEditFieldLabel    matlab.ui.control.Label
        DownlinkRTNSNRdBEditField       matlab.ui.control.EditField
        DownlinkRTNSNRdBEditFieldLabel  matlab.ui.control.Label
        DownlinkFWDSNRdBEditField       matlab.ui.control.EditField
        DownlinkFWDSNRdBEditFieldLabel  matlab.ui.control.Label
        ComplexRTNSNRdBEditField        matlab.ui.control.EditField
        ComplexRTNSNRdBEditFieldLabel   matlab.ui.control.Label
        UplinkRTNSNRdBEditField         matlab.ui.control.EditField
        UplinkRTNSNRdBEditFieldLabel    matlab.ui.control.Label
        ComplexFWDSNRdBEditField        matlab.ui.control.EditField
        ComplexFWDSNRdBEditFieldLabel   matlab.ui.control.Label
        UplinkFWDSNRdBEditField         matlab.ui.control.EditField
        UplinkFWDSNRdBEditFieldLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        ApiKey = '0060c72dceccf032a191f0bc76e2c2b4'
        SatTable
        ModcodTable
        ModcodArray
        temptable
        lattable
        longtable
        rainheighttable
        elevationtable
        R001table
        FreqCoefTable

        % Hub Parameters
        HubLat = 3.1390
        HubLong = 101.6869
        HubLoc = 'Kuala Lumpur'
        HubD = 5 % Antenna Diameter in m
        HubP = 25 % Power in W        

        % 1st Vsat Parameters
        VsatLat = 5.9788
        VsatLong = 116.0753
        VsatLoc = 'Sabah'
        VsatD = 1 % Antenna Diameter in m
        VsatP = 16 % Power in W

        % 2nd Vsat Parameters
        VsatLat2 = 5.3546
        VsatLong2 = 100.0828
        VsatLoc2 = 'Penang'
        VsatD2 = 2 % Antenna Diameter in m
        VsatP2 = 20 % Power in W

        % Satellite Parameters
        SatName = 'MEASAT 3D'
        SatDate = '22-Jun-22'
        SatLat = 0
        SatLong = 91.5
        SatAlt = 35786 % Altitude in KMs
        SatG = 52.1 % Gain in dBi
        SatT = 30 % Noise Temp in K
        SatLNB = 45 % Noise Temp in K
        SatFeed = 0.3 % Feeder Loss dB
        SatP = 6 % Power in W
        SatD = 3 % Antenna Diameter in m
        TBW = 36 % Transponder Bandwith in MHz
        CBW = 1 % Channel Bandwith in MHz
        HPAComp = 2.7 % HPA Compression in dB
        
        % Additional Parameters
        Re = 6378 % Earth Radius in KMs
        bandhigh = 2
        bandlow = 1
        Bandticklow = {1;2;4;8;12;26}
        Bandtickhigh = {2;4;8;12;18;40}
        Bandnames = ["L-Band";"S-Band";"C-Band";"X-Band";"Ku-Band";"Ka-Band"]
        elevationlimit = 15
        antapef = 0.6 % Antenna Aperture Efficiency
        C = 3*10.^8 % Speed of Light in m/s
        CosineRolloff = 1.2
        T = 24.1 % Ground noise temperature in dBK
        BoltzmanConstant = -228.6;
        AtmosLoss = 0.2; % Atmospheric absorption in dB
        PointLoss = 0.3; % Pointing Loss in dB
        FWDEbN0
        RTNEbN0
        ComplexEbN0
        SatelliteHubSlantRange
        HubtoSatFSPL
        HubVisT = 0
        HubElevation = 0
        HubRainAttenuationUplink = 0
        HubRainAttenuationDownlink = 0
        SatelliteVsatSlantRange
        VsattoSatFSPL
        VsatVisT = 0
        VsatElevation = 0
        VsatRainAttenuationUplink = 0
        VsatRainAttenuationDownlink = 0
        SatelliteVsatSlantRange2
        VsattoSatFSPL2        
        VsatVisT2 = 0
        VsatElevation2 = 0
        VsatRainAttenuationUplink2 = 0
        VsatRainAttenuationDownlink2 = 0
        SatPFD
        checkbox = 0
    end
    
    methods (Access = private)
        
        function [latDegs, lonDegs] = GetCoor(app,Loc)
            yqlUrl = ['http://api.positionstack.com/v1/forward?access_key=' app.ApiKey '&query=' Loc];
            % retrieve the data
            xmlData = webread(yqlUrl);
            % get the latitude and longitude
            latDegs = xmlData.data.latitude;
            lonDegs = xmlData.data.longitude;
        end
        
        function gam = gamma(~,SatLat,SatLong,EarthLat,EarthLong)
            a = sind(SatLat)*sind(EarthLat);
            b = cosd(SatLat)*cosd(EarthLat)*cosd(SatLong-EarthLong);
            gam = acosd(a+b);
        end

        function VisT = visibilitytest(~,gamma,re,rs)
            a = acosd(re/rs);
            if gamma <= a
                VisT = true;
            else
                VisT = false;
            end
        end

        function elv = elevation(~,gamma,re,rs)
            a = sind(gamma);
            b = sqrt(1+(re/rs).^2-(2*(re/rs)*cosd(gamma)));
            elv = acosd(a/b);
        end

        function [alpha,az] = azimuth(~,SatLat,SatLong,EarthLat,EarthLong,gamma)
            a = sind(abs(EarthLong-SatLong))*(cosd(SatLat)/sind(gamma));
            alpha = asind(a);
            b = tand(EarthLat)*cosd(SatLong-EarthLong);
            c = tand(SatLat);
            if b > c && SatLong > EarthLong
                az = 180 - alpha;
            elseif b < c && SatLong < EarthLong
                az = 360 - alpha;
            elseif b > c && SatLong < EarthLong
                az = 180 + alpha;
            else
                az = alpha;
            end
        end

        function sr = slantrange(~,re,rs,elevation)
            a = (re*cosd(elevation)).^2 + rs.^2;
            b = -re.^2 - re*cosd(elevation);
            sr = sqrt(a+b);
        end

        function fsl = fspl(~,slantrange,f)
            fsl = 20*log10(slantrange) + 20*log10(f) + 92.45;
        end

        function linkmargin(app,FWDEbN0,RTNEbN0,EbN0,ComplexEbN0)
            app.FWDLinkMargindBEditField.Value = string(round((EbN0 - FWDEbN0),3));
            if (EbN0 - FWDEbN0) >= 0
                app.FWDLamp.Color = 'green';
            else
                app.FWDLamp.Color = 'red';
            end
            app.RTNLinkMargindBEditField.Value = string(round((EbN0 - RTNEbN0),3));
            if (EbN0 - RTNEbN0) >= 0
                app.RTNLamp.Color = 'green';
            else
                app.RTNLamp.Color = 'red';
            end
            app.ComplexLinkMargindBEditField.Value = string(round((EbN0 - ComplexEbN0),3));
        end

        function getEbN0(app)
            x = table2array(app.ModcodTable(:,1));
            y = split(x(contains(x,app.SpreadingDropDown.Value)),"|");
            app.ModulationDropDown.Items = unique(y(:,2));
            y = split(x(contains(x,app.ModulationDropDown.Value)),"|");
            app.CodeRateDropDown.Items = unique(y(:,3));
            x = find(strcmp(app.ModcodTable.MODCOD, app.SpreadingDropDown.Value + "|" + ...
                app.ModulationDropDown.Value + "|" + app.CodeRateDropDown.Value));
            y = string(table2array(app.ModcodTable(x,app.ChipRateMchipssDropDown.Value)));
            app.RequiredEbN0dBEditField.Value = y;
            if app.HubVisT == 1 && app.HubElevation >= app.elevationlimit && app.VsatVisT == 1 && app.VsatElevation >= app.elevationlimit  && app.VsatVisT2 == 1 && app.VsatElevation2 >= app.elevationlimit
                linkmargin(app,app.FWDEbN0,app.RTNEbN0,str2double(app.RequiredEbN0dBEditField.Value),app.ComplexEbN0);
            end
        end

        function updatefreqsliders(app)
           app.UplinkFrequencyGHzSlider.Limits = [app.bandlow,app.bandhigh];
           app.UplinkFrequencyGHzSlider.MajorTicks = app.bandlow:((app.bandhigh-app.bandlow)/10):app.bandhigh;
           app.UplinkFrequencyGHzSlider.MinorTicks = app.bandlow:((app.bandhigh-app.bandlow)/50):app.bandhigh;
           app.UplinkFrequencyGHzSlider.Value = app.bandlow;
           app.UplinkFreq.Value = num2str(app.bandlow);
           app.DownlinkFrequencyGHzSlider.Limits = [app.bandlow,app.bandhigh];
           app.DownlinkFrequencyGHzSlider.MajorTicks = app.bandlow:((app.bandhigh-app.bandlow)/10):app.bandhigh;
           app.DownlinkFrequencyGHzSlider.MinorTicks = app.bandlow:((app.bandhigh-app.bandlow)/50):app.bandhigh;
           app.DownlinkFrequencyGHzSlider.Value = app.bandlow;
           app.DownlinkFreq.Value = num2str(app.bandlow);
        end

        function updatepfdlamp(app)
            if (6.7 < str2double(app.DownlinkFreq.Value)) && (str2double(app.DownlinkFreq.Value) < 6.825)
                if app.SatPFD < -127
                    app.Lamp.Color = 'green';
                else
                    app.Lamp.Color = 'red';
                end
            elseif (6.825 < str2double(app.DownlinkFreq.Value)) && (str2double(app.DownlinkFreq.Value) < 7.075)
                if app.SatPFD < -124
                    app.Lamp.Color = 'green';
                else
                    app.Lamp.Color = 'red';
                end
            elseif (19.3 < str2double(app.DownlinkFreq.Value)) && (str2double(app.DownlinkFreq.Value) < 19.6)
                if app.SatPFD < -105
                    app.Lamp.Color = 'green';
                else
                    app.Lamp.Color = 'red';
                end
            else
                app.Lamp.Color = 'green';
            end
        end
        function Ap = getheight(app,latitude,longitude,elevationangle,frequency,rainavailability)
            latitude = round(latitude/1.5)*1.5;
            longitude = round(longitude/1.5)*1.5;
            index = find(app.lattable(:,1) == latitude);
            index2 = find(app.longtable(1,:) == longitude);
            h0 = table2array(app.rainheighttable(index,index2));
            hs = (app.elevationtable(index-1,index2-1)/app.elevationtable(41,178))*8.849;
            R001val = table2array(app.R001table(index,index2));
            hr = h0 + 0.36; 
            Ls = (hr-hs)/sind(elevationangle);
            Lg = Ls*cosd(elevationangle);
            for i = (1:116)
                if (app.FreqCoefTable(i,1) <= frequency) && (frequency < app.FreqCoefTable(i+1,1))
                    k = app.FreqCoefTable(i,2);
                    a = app.FreqCoefTable(i,3);
                end
            end
            yr = k*(R001val).^a;
            r001 = 1 / (1+0.78*sqrt((Lg*yr)/frequency)-0.38*(1-exp(-2*Lg)));
            check = atand((hr-hs)/(Lg*r001));
            if check > elevationangle
                Lr = (Lg*r001)/cosd(elevationangle);
            else
                Lr = (hr-hs)/sind(elevationangle);
            end
            if abs(latitude) < 36
                x = 36 - abs(latitude);
            else
                x = 0;
            end
            v001 = 1 / (1+sqrt(sind(elevationangle))*(31*(1-exp(-(elevationangle/(1+x))))*(sqrt(Lr*yr)/frequency.^2)-0.45));
            Le = Lr*v001;
            A001 = yr*Le;
            p = 100 - rainavailability;
            if p >= 1 || abs(latitude) >= 36
                b = 0;
            elseif p <= 1 && abs(latitude) < 36 && elevationangle >= 25
                b = -0.005*(abs(latitude)-36);
            else
                b = -0.005*(abs(latitude)-36)+1.8-4.25*sind(elevationangle);
            end    
            exponent = -(0.655+0.033*log(p)-0.045*log(A001)-b*(1-p)*sind(elevationangle));
            Ap = A001*(p/0.01).^exponent;
        end
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.8;
            height = screenHeight*0.8;
            drawnow;
            app.UIFigure.Position = [left bottom width height]; 
            
            app.HubLocEditField.Value = app.HubLoc;
            app.HubLatitudeEditField.Value = string(app.HubLat);
            app.HubLongitudeEditField.Value = string(app.HubLong);
            app.HubAntennaDiametermEditField.Value = string(app.HubD);
            app.HubPowerWEditField.Value = string(app.HubP);

            app.VsatLocEditField.Value = app.VsatLoc;
            app.VsatLatitudeEditField.Value = string(app.VsatLat);
            app.VsatLongitudeEditField.Value = string(app.VsatLong);
            app.VsatAntennaDiametermEditField.Value = string(app.VsatD);
            app.VsatPowerWEditField.Value = string(app.VsatP);

            app.VsatLocEditField_2.Value = app.VsatLoc2;
            app.VsatLatitudeEditField_2.Value = string(app.VsatLat2);
            app.VsatLongitudeEditField_2.Value = string(app.VsatLong2);
            app.VsatAntennaDiametermEditField_2.Value = string(app.VsatD2);
            app.VsatPowerWEditField_2.Value = string(app.VsatP2);

            app.SatLongitudeEditField.Value = string(app.SatLong);
            app.SatDateEditField.Value = app.SatDate;
            app.SatGaindBEditField.Value = string(app.SatG);
            app.SatTempKEditField.Value = string(app.SatT);
            app.LNBTempKEditField.Value = string(app.SatLNB);
            app.FeederLossdBEditField.Value = string(app.SatFeed);
            app.SatPowerWEditField.Value = string(app.SatP);
            app.SatAntennaDiametermEditField.Value = string(app.SatD);
            app.ChannelBandwithMHzEditField.Value = string(app.CBW);
            app.TransponderBandwithMHzEditField.Value = string(app.TBW);

            app.AntennaApertureEfficiencyEditField.Value = string(app.antapef);
            app.PointingLossdBEditField.Value = string(app.PointLoss);
            app.AtmosphericLossdBEditField.Value = string(app.AtmosLoss);

            updatefreqsliders(app)
            app.SatelliteHPACompressiondBEditField.Value = string(app.HPAComp);

            app.SatTable = readtable("Satellites.csv");
            app.SatNameDropDown.Items = table2array(app.SatTable(:,1));
            app.ModcodTable = readtable("Modcods.csv", 'VariableNamingRule','preserve');
            app.ModcodArray = split(table2array(app.ModcodTable(:,1)),"|");
            app.SpreadingDropDown.Items = unique(app.ModcodArray(:,1));
            app.ChipRateMchipssDropDown.Items = app.ModcodTable.Properties.VariableNames(2:end);
            getEbN0(app)

            app.temptable = array2table(ones(121,241)*180);
            app.lattable = table2array(readtable("Lat.txt"));
            app.longtable = readtable("Lon.txt");
            app.longtable = app.longtable{:,:} - app.temptable{:,:};
            app.rainheighttable = readtable("h0.txt");
            app.elevationtable = im2double(imread("elevation120x240.pbm"));
            app.R001table = load("R001.mat").R001final;
            app.FreqCoefTable = table2array(readtable("FreqCo.csv"));
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.CalculationsTab;
            if app.checkbox
                SatGoverT = str2double(app.SatGTEditField.Value);
                SatEIRP = str2double(app.SatEIRPdBEditField.Value);
            else    
                SatGoverT = app.SatG - 10*log10(app.SatT+290*((10.^(app.SatFeed/10))-1)+app.SatLNB*(10.^(app.SatFeed/10))); % G/T in dB/K
                SatEIRP = 10*log10(app.SatP) + 10*log10(app.antapef*(10.472*str2double(app.DownlinkFreq.Value)*app.SatD).^2);
            end

            app.SatelliteGTdBKEditField.Value = string(round(SatGoverT,2));
            app.SatelliteEIRPdBEditField.Value = string(round(SatEIRP,2));
            app.SatelliteSFDdBWm2EditField.Value = "";
            app.SatellitePFDdBWm2EditField.Value = "";
            app.Lamp.Color = 'red';

            Rs = app.SatAlt + app.Re; % Satellite Radius in KMs
            HubGamma = gamma(app,app.SatLat,app.SatLong,app.HubLat,app.HubLong);
            app.HubVisT = visibilitytest(app,HubGamma,app.Re,Rs);
            app.HubElevation = elevation(app,HubGamma,app.Re,Rs);
            if app.HubVisT == 1 && app.HubElevation >= app.elevationlimit
                [~,HubAzimuth] = azimuth(app,app.SatLat,app.SatLong,app.HubLat,app.HubLong,HubGamma);
                app.SatelliteHubSlantRange = slantrange(app,app.Re,Rs,app.HubElevation);
                app.HubtoSatFSPL = fspl(app,app.SatelliteHubSlantRange,str2double(app.UplinkFreq.Value));
                SatSFD = SatEIRP - app.HubtoSatFSPL - 10*log10(app.C.^2/((str2double(app.DownlinkFreq.Value)*10.^9).^2*4*pi));
                app.SatPFD = SatEIRP - 20*log10(app.SatelliteHubSlantRange*10.^3) - 10*log10(4*pi); % Power Flux Density in dBW/m^2
                if app.Lamp.Color(1) == 1
                    updatepfdlamp(app)
                end

                if app.SatelliteSFDdBWm2EditField.Value == "" && app.SatellitePFDdBWm2EditField.Value == ""
                    app.SatelliteSFDdBWm2EditField.Value = string(round(SatSFD,2));
                    app.SatellitePFDdBWm2EditField.Value = string(round(app.SatPFD,2));
                end
                app.HubSatelliteVisibilityLamp.Color = 'green';
                app.HubElevationAngledegEditField.Value = string(round(app.HubElevation,2));
                app.HubAzimuthAngledegEditField.Value = string(round(HubAzimuth,2));
                app.HubSlantRangeKmEditField.Value = string(round(app.SatelliteHubSlantRange,2));
                app.HubFreeSpaceLossdBEditField.Value = string(round(app.HubtoSatFSPL,2));
               
                HubPG = 10*log10(app.antapef*(pi*app.HubD*str2double(app.UplinkFreq.Value)*10.^9/app.C).^2); % Peak Gain in dBi
                HubG = HubPG + app.CosineRolloff*10*log10(cosd(90-app.HubElevation)); % Gain in dBi   
                HubSFD = SatSFD - 10*log10(app.TBW/app.CBW); % Saturation Flux Density in dBW/m^2
                HubEIRP = 10*log10(app.HubP) + HubG; % EIRP in dB
                HubPFD = HubEIRP - 20*log10(app.SatelliteHubSlantRange*10.^3) - 10*log10(4*pi); % Power Flux Density in dBW/m^2
                HubGoverT = HubG - app.T; % G/T in dB/K
                
                if app.EnableCheckBox.Value == 1
                    app.HubRainAttenuationUplink = getheight(app,app.HubLat,app.HubLong,app.HubElevation,str2double(app.UplinkFreq.Value),app.LinkRainAvailabilityEditField.Value);
                    app.HubRainAttenuationDownlink = getheight(app,app.HubLat,app.HubLong,app.HubElevation,str2double(app.DownlinkFreq.Value),app.LinkRainAvailabilityEditField.Value);

                    app.HubUplinkAttenuationdBEditField.Value = string(round(app.HubRainAttenuationUplink,2));
                    app.HubDownlinkAttenuationdBEditField.Value = string(round(app.HubRainAttenuationDownlink,2));
                else
                    app.HubRainAttenuationUplink = 0;
                    app.HubRainAttenuationDownlink = 0;
                    app.HubUplinkAttenuationdBEditField.Value = "";
                    app.HubDownlinkAttenuationdBEditField.Value = "";
                end

                app.HubGaindBiEditField.Value = string(round(HubG,2));
                app.HubSFDEditField.Value = string(round(HubSFD,2));
                app.HubPFDEditField.Value = string(round(HubPFD,2));
                app.HubEIRPdBEditField.Value = string(round(HubEIRP,2));
                app.HubGTEditField.Value = string(round(HubGoverT,2));
            else
                app.SatelliteSFDdBWm2EditField.Value = "";
                app.SatellitePFDdBWm2EditField.Value = "";
                app.HubSatelliteVisibilityLamp.Color = 'red';
                app.HubElevationAngledegEditField.Value = "";
                app.HubAzimuthAngledegEditField.Value = "";
                app.HubSlantRangeKmEditField.Value = "";
                app.HubFreeSpaceLossdBEditField.Value = "";
                app.HubGaindBiEditField.Value = "";
                app.HubSFDEditField.Value = "";
                app.HubPFDEditField.Value = "";
                app.HubEIRPdBEditField.Value = "";
                app.HubGTEditField.Value = "";
                app.HubUplinkAttenuationdBEditField.Value = "";
                app.HubDownlinkAttenuationdBEditField.Value = "";
            end

            VsatGamma = gamma(app,app.SatLat,app.SatLong,app.VsatLat,app.VsatLong);
            app.VsatVisT = visibilitytest(app,VsatGamma,app.Re,Rs);
            app.VsatElevation = elevation(app,VsatGamma,app.Re,Rs);
            if  app.VsatVisT == 1 && app.VsatElevation >= app.elevationlimit              
                [~, VsatAzimuth] = azimuth(app,app.SatLat,app.SatLong,app.VsatLat,app.VsatLong,VsatGamma);
                app.SatelliteVsatSlantRange = slantrange(app,app.Re,Rs,app.VsatElevation);
                app.VsattoSatFSPL = fspl(app,app.SatelliteVsatSlantRange,str2double(app.UplinkFreq.Value));
                SatSFD = SatEIRP - app.HubtoSatFSPL - 10*log10(app.C.^2/((str2double(app.DownlinkFreq.Value)*10.^9).^2*4*pi));
                app.SatPFD = SatEIRP - 20*log10(app.SatelliteHubSlantRange*10.^3) - 10*log10(4*pi); % Power Flux Density in dBW/m^2
                if app.Lamp.Color(1) == 1
                    updatepfdlamp(app)
                end

                if app.SatelliteSFDdBWm2EditField.Value == "" && app.SatellitePFDdBWm2EditField.Value == ""
                    app.SatelliteSFDdBWm2EditField.Value = string(round(SatSFD,2));
                    app.SatellitePFDdBWm2EditField.Value = string(round(app.SatPFD,2));
                end               
                app.VsatSatelliteVisibilityLamp.Color = 'green';
                app.VsatElevationAngledegEditField.Value = string(round(app.VsatElevation,2));
                app.VsatAzimuthAngledegEditField.Value = string(round(VsatAzimuth,2));
                app.VsatSlantRangeKmEditField.Value = string(round(app.SatelliteVsatSlantRange,2));
                app.VsatFreeSpaceLossdBEditField.Value = string(round(app.VsattoSatFSPL,2));
                
                VsatPG = 10*log10(app.antapef*(pi*app.VsatD*str2double(app.UplinkFreq.Value)*10.^9/app.C).^2); % Peak Gain in dBi
                VsatG = VsatPG + app.CosineRolloff*10*log10(cosd(90-app.VsatElevation)); % Gain in dBi   
                VsatSFD = SatSFD - 10*log10(app.TBW/app.CBW); % Saturation Flux Density in dBW/m^2
                VsatEIRP = 10*log10(app.VsatP) + VsatG; % EIRP in dB
                VsatPFD = VsatEIRP - 20*log10(app.SatelliteVsatSlantRange*10.^3) - 10*log10(4*pi); % Power Flux Density in dBW/m^2
                VsatGoverT = VsatG - app.T; % G/T in dB/K
                
                if app.EnableCheckBox.Value == 1
                    app.VsatRainAttenuationUplink = getheight(app,app.VsatLat,app.VsatLong,app.VsatElevation,str2double(app.UplinkFreq.Value),app.LinkRainAvailabilityEditField.Value);
                    app.VsatRainAttenuationDownlink = getheight(app,app.VsatLat,app.VsatLong,app.VsatElevation,str2double(app.DownlinkFreq.Value),app.LinkRainAvailabilityEditField.Value);

                    app.stVsatUplinkAttenuationdBEditField.Value = string(round(app.VsatRainAttenuationUplink,2));
                    app.stVsatDownlinkAttenuationdBEditField.Value = string(round(app.VsatRainAttenuationDownlink,2));
                else
                    app.VsatRainAttenuationUplink = 0;
                    app.VsatRainAttenuationDownlink = 0;
                    app.stVsatUplinkAttenuationdBEditField.Value = "";
                    app.stVsatDownlinkAttenuationdBEditField.Value = "";
                end
                
                app.VsatGaindBiEditField.Value = string(round(VsatG,2));
                app.VsatSFDEditField.Value = string(round(VsatSFD,2));
                app.VsatPFDEditField.Value = string(round(VsatPFD,2));
                app.VsatEIRPdBEditField.Value = string(round(VsatEIRP,2));
                app.VsatGTEditField.Value = string(round(VsatGoverT,2));
            else
                app.SatelliteSFDdBWm2EditField.Value = "";
                app.SatellitePFDdBWm2EditField.Value = "";
                app.VsatSatelliteVisibilityLamp.Color = 'red';
                app.VsatElevationAngledegEditField.Value = "";
                app.VsatAzimuthAngledegEditField.Value = "";
                app.VsatSlantRangeKmEditField.Value = "";
                app.VsatFreeSpaceLossdBEditField.Value = "";
                app.VsatGaindBiEditField.Value = "";
                app.VsatSFDEditField.Value = "";
                app.VsatPFDEditField.Value = "";
                app.VsatEIRPdBEditField.Value = "";
                app.VsatGTEditField.Value = "";
                app.stVsatUplinkAttenuationdBEditField.Value = "";
                app.stVsatDownlinkAttenuationdBEditField.Value = "";
            end

            VsatGamma2 = gamma(app,app.SatLat,app.SatLong,app.VsatLat2,app.VsatLong2);
            app.VsatVisT2 = visibilitytest(app,VsatGamma2,app.Re,Rs);
            app.VsatElevation2 = elevation(app,VsatGamma2,app.Re,Rs);
            if  app.VsatVisT2 == 1 && app.VsatElevation2 >= app.elevationlimit              
                [~, VsatAzimuth2] = azimuth(app,app.SatLat,app.SatLong,app.VsatLat2,app.VsatLong2,VsatGamma2);
                app.SatelliteVsatSlantRange2 = slantrange(app,app.Re,Rs,app.VsatElevation2);
                app.VsattoSatFSPL2 = fspl(app,app.SatelliteVsatSlantRange2,str2double(app.UplinkFreq.Value));
                SatSFD = SatEIRP - app.HubtoSatFSPL - 10*log10((app.C.^2/(str2double(app.DownlinkFreq.Value)*10.^9).^2*4*pi));
                app.SatPFD = SatEIRP - 20*log10(app.SatelliteHubSlantRange*10.^3) - 10*log10(4*pi); % Power Flux Density in dBW/m^2
                if app.Lamp.Color(1) == 1
                    updatepfdlamp(app)
                end

                if app.SatelliteSFDdBWm2EditField.Value == "" && app.SatellitePFDdBWm2EditField.Value == ""
                    app.SatelliteSFDdBWm2EditField.Value = string(round(SatSFD,2));
                    app.SatellitePFDdBWm2EditField.Value = string(round(app.SatPFD,2));
                end                
                app.VsatSatelliteVisibilityLamp_2.Color = 'green';
                app.VsatElevationAngledegEditField_2.Value = string(round(app.VsatElevation2,2));
                app.VsatAzimuthAngledegEditField_2.Value = string(round(VsatAzimuth2,2));
                app.VsatSlantRangeKmEditField_2.Value = string(round(app.SatelliteVsatSlantRange2,2));
                app.VsatFreeSpaceLossdBEditField_2.Value = string(round(app.VsattoSatFSPL2,2));
                
                VsatPG2 = 10*log10(app.antapef*(pi*app.VsatD2*str2double(app.UplinkFreq.Value)*10.^9/app.C).^2); % Peak Gain in dBi
                VsatG2 = VsatPG2 + app.CosineRolloff*10*log10(cosd(90-app.VsatElevation2)); % Gain in dBi   
                VsatSFD2 = SatSFD - 10*log10(app.TBW/app.CBW); % Saturation Flux Density in dBW/m^2
                VsatEIRP2 = 10*log10(app.VsatP2) + VsatG2; % EIRP in dB
                VsatPFD2 = VsatEIRP2 - 20*log10(app.SatelliteVsatSlantRange2*10.^3) - 10*log10(4*pi); % Power Flux Density in dBW/m^2
                VsatGoverT2 = VsatG2 - app.T; % G/T in dB/K
                
                if app.EnableCheckBox.Value == 1
                    app.VsatRainAttenuationUplink2 = getheight(app,app.VsatLat2,app.VsatLong2,app.VsatElevation2,str2double(app.UplinkFreq.Value),app.LinkRainAvailabilityEditField.Value);
                    app.VsatRainAttenuationDownlink2 = getheight(app,app.VsatLat2,app.VsatLong2,app.VsatElevation2,str2double(app.DownlinkFreq.Value),app.LinkRainAvailabilityEditField.Value);

                    app.ndVsatUplinkAttenuationdBEditField.Value = string(round(app.VsatRainAttenuationUplink2,2));
                    app.ndVsatDownlinkAttenuationdBEditField.Value = string(round(app.VsatRainAttenuationDownlink2,2));
                else
                    app.VsatRainAttenuationUplink2 = 0;
                    app.VsatRainAttenuationDownlink2 = 0;
                    app.ndVsatUplinkAttenuationdBEditField.Value = "";
                    app.ndVsatDownlinkAttenuationdBEditField.Value = "";
                end

                app.VsatGaindBiEditField_2.Value = string(round(VsatG2,2));
                app.VsatSFDEditField_2.Value = string(round(VsatSFD2,2));
                app.VsatPFDEditField_2.Value = string(round(VsatPFD2,2));
                app.VsatEIRPdBEditField_2.Value = string(round(VsatEIRP2,2));
                app.VsatGTEditField_2.Value = string(round(VsatGoverT2,2));
            else
                app.SatelliteSFDdBWm2EditField.Value = "";
                app.SatellitePFDdBWm2EditField.Value = "";
                app.VsatSatelliteVisibilityLamp_2.Color = 'red';
                app.VsatElevationAngledegEditField_2.Value = "";
                app.VsatAzimuthAngledegEditField_2.Value = "";
                app.VsatSlantRangeKmEditField_2.Value = "";
                app.VsatFreeSpaceLossdBEditField_2.Value = "";
                app.VsatGaindBiEditField_2.Value = "";
                app.VsatSFDEditField_2.Value = "";
                app.VsatPFDEditField_2.Value = "";
                app.VsatEIRPdBEditField_2.Value = "";
                app.VsatGTEditField_2.Value = "";
                app.ndVsatUplinkAttenuationdBEditField.Value = "";
                app.ndVsatDownlinkAttenuationdBEditField.Value = "";
            end

               % Mesh Network
            if app.AccessSchemeDropDown.Value == "SCPC" && app.HubVisT == 1 && app.HubElevation >= app.elevationlimit && app.VsatVisT == 1 && app.VsatElevation >= app.elevationlimit && app.VsatVisT2 == 1 && app.VsatElevation2 >= app.elevationlimit 
                UplinkFWDSNR = 10*log10(app.VsatP2) + VsatG2 - 10*log10(app.CBW * 10.^6) - app.VsattoSatFSPL2 - app.VsatRainAttenuationUplink2 - app.AtmosLoss - app.PointLoss + SatGoverT - (app.BoltzmanConstant);
                DownlinkFWDSNR = SatEIRP - (VsatSFD2 - VsatPFD2 + app.HPAComp) - 10*log10(app.TBW * 10.^6) - app.VsattoSatFSPL - app.VsatRainAttenuationDownlink - app.AtmosLoss - app.PointLoss + VsatGoverT - (app.BoltzmanConstant);
                FWDSNR = 10*log10(1/((1/10.^(UplinkFWDSNR/10))+(1/10.^(DownlinkFWDSNR/10))));
                FWDDR = (app.CBW * 1000000 * log2(1 + FWDSNR))/1000000;
                app.FWDEbN0 = (2.^(2*((FWDDR/1000000)/2*app.CBW*1000000))-1)/(2*((FWDDR/1000000)/2*app.CBW*1000000));

                UplinkRTNSNR = 10*log10(app.VsatP) + VsatG - 10*log10(app.CBW * 10.^6) - app.VsattoSatFSPL - app.VsatRainAttenuationUplink - app.AtmosLoss - app.PointLoss + SatGoverT - (app.BoltzmanConstant);
                DownlinkRTNSNR = SatEIRP - (VsatSFD - VsatPFD + app.HPAComp) - 10*log10(app.TBW * 10.^6) - app.VsattoSatFSPL2 - app.VsatRainAttenuationDownlink2 - app.AtmosLoss - app.PointLoss + VsatGoverT2 - (app.BoltzmanConstant);
                RTNSNR = 10*log10(1/((1/10.^(UplinkRTNSNR/10))+(1/10.^(DownlinkRTNSNR/10))));
                RTNDR = (app.CBW * 1000000 * log2(1 + RTNSNR))/1000000;
                app.RTNEbN0 = (2.^(2*((RTNDR/1000000)/2*app.CBW*1000000))-1)/(2*((RTNDR/1000000)/2*app.CBW*1000000));

                ComplexSNR = 10*log10(1/((1/10.^(FWDSNR/10))+(1/10.^(RTNSNR/10))));
                ComplexDR = (app.CBW * 1000000 * log2(1 + ComplexSNR))/1000000;
                app.ComplexEbN0 = (2.^(2*((ComplexDR/1000000)/2*app.CBW*1000000))-1)/(2*((ComplexDR/1000000)/2*app.CBW*1000000));

                app.UplinkFWDSNRdBEditField.Value = num2str(round(UplinkFWDSNR,2));
                app.HubDownlinkFWDSNRdBEditField.Value = "";
                app.HubUplinkFWDSNRdBEditField.Value = "";
                app.DownlinkFWDSNRdBEditField.Value = num2str(round(DownlinkFWDSNR,2));
                app.ComplexFWDSNRdBEditField.Value = num2str(round(FWDSNR,2));
                app.UplinkRTNSNRdBEditField.Value = num2str(round(UplinkRTNSNR,2));
                app.HubDownlinkRTNSNRdBEditField.Value = "";
                app.HubUplinkRTNSNRdBEditField.Value = "";
                app.DownlinkRTNSNRdBEditField.Value = num2str(round(DownlinkRTNSNR,2));
                app.ComplexRTNSNRdBEditField.Value = num2str(round(RTNSNR,2));
                app.FWDBitrateMbpsEditField.Value = num2str(round(FWDDR,2));
                app.RTNBitrateMbpsEditField.Value = num2str(round(RTNDR,2));
                app.AvailableFWDEbN0dBEditField.Value = num2str(round(app.FWDEbN0,3));
                app.AvailableRTNEbN0dBEditField.Value = num2str(round(app.RTNEbN0,3));
                app.ComplexSNRdBEditField.Value = num2str(round(ComplexSNR,2));
                app.AvailableEbN0dBEditField.Value = num2str(round(app.ComplexEbN0,2));
                linkmargin(app,app.FWDEbN0,app.RTNEbN0,str2double(app.RequiredEbN0dBEditField.Value),app.ComplexEbN0);

                app.Image.ImageSource = 'Mesh.png';
                app.Label.Text = "Vsat to Sat to Vsat";

                %Star Network
            elseif (app.AccessSchemeDropDown.Value == "TDMA" || app.AccessSchemeDropDown.Value == "FDMA") && app.VsatVisT == 1 && app.VsatElevation >= app.elevationlimit && app.VsatVisT2 == 1 && app.VsatElevation2 >= app.elevationlimit 
                UplinkFWDSNR = 10*log10(app.VsatP2) + VsatG2 - 10*log10(app.CBW * 10.^6) - app.VsattoSatFSPL2 - app.VsatRainAttenuationUplink2 - app.AtmosLoss - app.PointLoss + SatGoverT - (app.BoltzmanConstant);
                DownlinkFWDHubSNR = SatEIRP - (VsatSFD2 - VsatPFD2 + app.HPAComp) - 10*log10(app.TBW * 10.^6) - app.HubtoSatFSPL - app.HubRainAttenuationDownlink - app.AtmosLoss - app.PointLoss + HubGoverT - (app.BoltzmanConstant);
                UplinkFWDHubSNR = 10*log10(app.HubP) + HubG - 10*log10(app.CBW * 10.^6) - app.HubtoSatFSPL - app.HubRainAttenuationUplink - app.AtmosLoss - app.PointLoss + SatGoverT - (app.BoltzmanConstant);
                DownlinkFWDSNR = SatEIRP - (HubSFD - HubPFD + app.HPAComp) - 10*log10(app.TBW * 10.^6) - app.VsattoSatFSPL - app.VsatRainAttenuationDownlink - app.AtmosLoss - app.PointLoss + VsatGoverT - (app.BoltzmanConstant);
                FWDSNR = 10*log10(1/((1/10.^(UplinkFWDSNR/10))+(1/10.^(DownlinkFWDHubSNR/10))+(1/10.^(UplinkFWDHubSNR/10))+(1/10.^(DownlinkFWDSNR/10))));
                FWDDR = (app.CBW * 1000000 * log2(1 + FWDSNR))/1000000;
                app.FWDEbN0 = (2.^(2*((FWDDR/1000000)/2*app.CBW*1000000))-1)/(2*((FWDDR/1000000)/2*app.CBW*1000000));

                UplinkRTNSNR = 10*log10(app.VsatP) + VsatG - 10*log10(app.CBW * 10.^6) - app.VsattoSatFSPL - app.VsatRainAttenuationUplink - app.AtmosLoss - app.PointLoss + SatGoverT - (app.BoltzmanConstant);
                DownlinkRTNHubSNR = SatEIRP - (VsatSFD - VsatPFD + app.HPAComp) - 10*log10(app.TBW * 10.^6) - app.HubtoSatFSPL - app.HubRainAttenuationDownlink - app.AtmosLoss - app.PointLoss + HubGoverT - (app.BoltzmanConstant);
                UplinkRTNHubSNR = 10*log10(app.HubP) + HubG - 10*log10(app.CBW * 10.^6) - app.HubtoSatFSPL - app.HubRainAttenuationUplink - app.AtmosLoss - app.PointLoss + SatGoverT - (app.BoltzmanConstant);
                DownlinkRTNSNR = SatEIRP - (HubSFD - HubPFD + app.HPAComp) - 10*log10(app.TBW * 10.^6) - app.VsattoSatFSPL2 - app.VsatRainAttenuationDownlink2 - app.AtmosLoss - app.PointLoss + VsatGoverT2 - (app.BoltzmanConstant);
                RTNSNR = 10*log10(1/((1/10.^(UplinkRTNSNR/10))+(1/10.^(DownlinkRTNHubSNR/10))+(1/10.^(UplinkRTNHubSNR/10))+(1/10.^(DownlinkRTNSNR/10))));
                RTNDR = (app.CBW * 1000000 * log2(1 + RTNSNR))/1000000;
                app.RTNEbN0 = (2.^(2*((RTNDR/1000000)/2*app.CBW*1000000))-1)/(2*((RTNDR/1000000)/2*app.CBW*1000000));

                ComplexSNR = 10*log10(1/((1/10.^(FWDSNR/10))+(1/10.^(RTNSNR/10))));
                ComplexDR = (app.CBW * 1000000 * log2(1 + ComplexSNR))/1000000;
                app.ComplexEbN0 = (2.^(2*((ComplexDR/1000000)/2*app.CBW*1000000))-1)/(2*((ComplexDR/1000000)/2*app.CBW*1000000));

                app.UplinkFWDSNRdBEditField.Value = num2str(round(UplinkFWDSNR,2));
                app.HubDownlinkFWDSNRdBEditField.Value = num2str(round(DownlinkFWDHubSNR,2));
                app.HubUplinkFWDSNRdBEditField.Value = num2str(round(UplinkFWDHubSNR,2));
                app.DownlinkFWDSNRdBEditField.Value = num2str(round(DownlinkFWDSNR,2));
                app.ComplexFWDSNRdBEditField.Value = num2str(round(FWDSNR,2));
                app.UplinkRTNSNRdBEditField.Value = num2str(round(UplinkRTNSNR,2));
                app.HubDownlinkRTNSNRdBEditField.Value = num2str(round(DownlinkRTNHubSNR,2));
                app.HubUplinkRTNSNRdBEditField.Value = num2str(round(UplinkRTNHubSNR,2));
                app.DownlinkRTNSNRdBEditField.Value = num2str(round(DownlinkRTNSNR,2));
                app.ComplexRTNSNRdBEditField.Value = num2str(round(RTNSNR,2));
                app.FWDBitrateMbpsEditField.Value = num2str(round(FWDDR,2));
                app.RTNBitrateMbpsEditField.Value = num2str(round(RTNDR,2));
                app.AvailableFWDEbN0dBEditField.Value = num2str(round(app.FWDEbN0,3));
                app.AvailableRTNEbN0dBEditField.Value = num2str(round(app.RTNEbN0,3));
                app.ComplexSNRdBEditField.Value = num2str(round(ComplexSNR,2));
                app.AvailableEbN0dBEditField.Value = num2str(round(app.ComplexEbN0,2));
                linkmargin(app,app.FWDEbN0,app.RTNEbN0,str2double(app.RequiredEbN0dBEditField.Value),app.ComplexEbN0);

                app.Image.ImageSource = 'Star.png';
                app.Label.Text = "Vsat to Sat to Hub to Sat to Vsat";

            else
                app.SatelliteGTdBKEditField.Value = "";
                app.SatelliteEIRPdBEditField.Value = "";
                app.UplinkFWDSNRdBEditField.Value = "";
                app.HubDownlinkFWDSNRdBEditField.Value = "";
                app.HubUplinkFWDSNRdBEditField.Value = "";
                app.DownlinkFWDSNRdBEditField.Value = "";
                app.ComplexFWDSNRdBEditField.Value = "";
                app.UplinkRTNSNRdBEditField.Value = "";
                app.HubDownlinkRTNSNRdBEditField.Value = "";
                app.HubUplinkRTNSNRdBEditField.Value = "";
                app.DownlinkRTNSNRdBEditField.Value = "";
                app.ComplexRTNSNRdBEditField.Value = "";
                app.FWDBitrateMbpsEditField.Value = "";
                app.RTNBitrateMbpsEditField.Value = "";
                app.AvailableFWDEbN0dBEditField.Value = "";
                app.AvailableRTNEbN0dBEditField.Value = "";
                app.ComplexSNRdBEditField.Value = "";
                app.AvailableEbN0dBEditField.Value = "";
                app.FWDLinkMargindBEditField.Value = "";
                app.RTNLinkMargindBEditField.Value = "";
                app.ComplexLinkMargindBEditField.Value = "";
                app.Image.ImageSource = '';
                app.Label.Text = "";
            end
        end

        % Value changed function: UplinkFreq
        function UplinkFreqValueChanged(app, event)
            value = str2double(app.UplinkFreq.Value);
            value2 = str2double(app.DownlinkFreq.Value);
            if value < app.bandlow
                app.bandlow = value;
                updatefreqsliders(app)
            elseif value > app.bandhigh
                app.bandhigh = value;
                updatefreqsliders(app)
                app.UplinkFreq.Value = num2str(value);
            end
            app.UplinkFrequencyGHzSlider.Value = value;
            app.DownlinkFreq.Value = num2str(value2);
            app.DownlinkFrequencyGHzSlider.Value = value2;
        end

        % Value changed function: DownlinkFreq
        function DownlinkFreqValueChanged(app, event)
            value = str2double(app.UplinkFreq.Value);
            value2 = str2double(app.DownlinkFreq.Value);
            if value2 < app.bandlow
                app.bandlow = value2;
                updatefreqsliders(app)
            elseif value2 > app.bandhigh
                app.bandhigh = value2;
                updatefreqsliders(app)
                app.DownlinkFreq.Value = num2str(value2);
            end
            app.UplinkFrequencyGHzSlider.Value = value;
            app.UplinkFreq.Value = num2str(value);
            app.DownlinkFrequencyGHzSlider.Value = value2;
        end

        % Value changed function: SatNameDropDown
        function SatNameDropDownValueChanged(app, event)
            app.SatName = app.SatNameDropDown.Value;
            x = find(strcmp(app.SatTable.name, app.SatName));
            app.SatLong = table2array(app.SatTable(x,"long"));
            app.SatLongitudeEditField.Value = string(app.SatLong);
            x = string(table2array(app.SatTable(x,"date")));
            app.SatDate = erase(x,'00');
            app.SatDateEditField.Value = string(app.SatDate);
        end

        % Value changed function: BandDropDown
        function BandDropDownValueChanged(app, event)
            for i = (1:6)
                if app.BandDropDown.Value == convertCharsToStrings(app.BandDropDown.Items{i})
                   app.bandhigh = app.Bandtickhigh{i};
                   app.bandlow = app.Bandticklow{i};
                   updatefreqsliders(app)
                end              
            end
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            app.checkbox = app.CheckBox.Value;
            if app.checkbox
                app.SatGTEditField.Editable = 'on';
                app.SatEIRPdBEditField.Editable = 'on';
                app.LNBTempKEditField.Editable = 'off';
                app.SatTempKEditField.Editable = 'off';
                app.SatGaindBEditField.Editable = 'off';
                app.FeederLossdBEditField.Editable = 'off';
                app.SatPowerWEditField.Editable = 'off';
                app.SatAntennaDiametermEditField.Editable = 'off';
            else
                app.SatGTEditField.Editable = 'off';
                app.SatEIRPdBEditField.Editable = 'off';
                app.LNBTempKEditField.Editable = 'on';
                app.SatTempKEditField.Editable = 'on';
                app.SatGaindBEditField.Editable = 'on';
                app.FeederLossdBEditField.Editable = 'on';
                app.SatPowerWEditField.Editable = 'on';
                app.SatAntennaDiametermEditField.Editable = 'on';
            end
        end

        % Button pushed function: HubSearchButton
        function HubSearchButtonPushed(app, event)
            [app.HubLat,app.HubLong] = GetCoor(app,app.HubLoc);
            app.HubLocEditField.Value = app.HubLoc;
            app.HubLatitudeEditField.Value = string(app.HubLat);
            app.HubLongitudeEditField.Value = string(app.HubLong);
        end

        % Value changing function: HubLongitudeEditField
        function HubLongitudeEditFieldValueChanging(app, event)
            app.HubLong = str2double(event.Value);
            app.HubLocEditField.Value = '';
        end

        % Value changing function: HubLatitudeEditField
        function HubLatitudeEditFieldValueChanging(app, event)
            app.HubLat = str2double(event.Value);
            app.HubLocEditField.Value = '';
        end

        % Value changing function: HubLocEditField
        function HubLocEditFieldValueChanging(app, event)
            app.HubLoc = event.Value;
        end

        % Value changing function: HubPowerWEditField
        function HubPowerWEditFieldValueChanging(app, event)
            app.HubP = str2double(event.Value);            
        end

        % Value changing function: HubAntennaDiametermEditField
        function HubAntennaDiametermEditFieldValueChanging(app, event)
            app.HubD = str2double(event.Value);            
        end

        % Button pushed function: VsatSearchButton
        function VsatSearchButtonPushed(app, event)
            [app.VsatLat,app.VsatLong] = GetCoor(app,app.VsatLoc);
            app.VsatLocEditField.Value = app.VsatLoc;
            app.VsatLatitudeEditField.Value = string(app.VsatLat);
            app.VsatLongitudeEditField.Value = string(app.VsatLong);
        end

        % Value changing function: VsatLocEditField
        function VsatLocEditFieldValueChanging(app, event)
            app.VsatLoc = event.Value;
        end

        % Value changing function: VsatLatitudeEditField
        function VsatLatitudeEditFieldValueChanging(app, event)
            app.VsatLat = str2double(event.Value);   
            app.VsatLocEditField.Value = '';
        end

        % Value changing function: VsatLongitudeEditField
        function VsatLongitudeEditFieldValueChanging(app, event)
            app.VsatLong = str2double(event.Value);    
            app.VsatLocEditField.Value = '';
        end

        % Value changing function: VsatAntennaDiametermEditField
        function VsatAntennaDiametermEditFieldValueChanging(app, event)
            app.VsatD = str2double(event.Value);            
        end

        % Value changing function: VsatPowerWEditField
        function VsatPowerWEditFieldValueChanging(app, event)
            app.VsatP = str2double(event.Value);            
        end

        % Button pushed function: VsatSearchButton_2
        function VsatSearchButton_2Pushed(app, event)
            [app.VsatLat2,app.VsatLong2] = GetCoor(app,app.VsatLoc2);
            app.VsatLocEditField_2.Value = app.VsatLoc2;
            app.VsatLatitudeEditField_2.Value = string(app.VsatLat2);
            app.VsatLongitudeEditField_2.Value = string(app.VsatLong2);
        end

        % Value changing function: VsatLocEditField_2
        function VsatLocEditField_2ValueChanging(app, event)
            app.VsatLoc2 = event.Value;            
        end

        % Value changing function: VsatLatitudeEditField_2
        function VsatLatitudeEditField_2ValueChanging(app, event)
            app.VsatLat2 = str2double(event.Value);   
            app.VsatLocEditField_2.Value = '';
        end

        % Value changing function: VsatLongitudeEditField_2
        function VsatLongitudeEditField_2ValueChanging(app, event)
            app.VsatLong2 = str2double(event.Value);    
            app.VsatLocEditField_2.Value = '';
        end

        % Value changing function: VsatAntennaDiametermEditField_2
        function VsatAntennaDiametermEditField_2ValueChanging(app, event)
            app.VsatD2 = str2double(event.Value);  
        end

        % Value changing function: VsatPowerWEditField_2
        function VsatPowerWEditField_2ValueChanging(app, event)
            app.VsatP2 = str2double(event.Value); 
        end

        % Value changing function: ChannelBandwithMHzEditField
        function ChannelBandwithMHzEditFieldValueChanging(app, event)
            app.CBW = str2double(event.Value);            
        end

        % Value changing function: SatGaindBEditField
        function SatGaindBEditFieldValueChanging(app, event)
            app.SatG = str2double(event.Value);            
        end

        % Value changing function: SatTempKEditField
        function SatTempKEditFieldValueChanging(app, event)
            app.SatT = str2double(event.Value);            
        end

        % Value changing function: SatPowerWEditField
        function SatPowerWEditFieldValueChanging(app, event)
            app.SatP = str2double(event.Value);            
        end

        % Value changing function: SatAntennaDiametermEditField
        function SatAntennaDiametermEditFieldValueChanging(app, event)
            app.SatD = str2double(event.Value);            
        end

        % Value changing function: TransponderBandwithMHzEditField
        function TransponderBandwithMHzEditFieldValueChanging(app, event)
            app.TBW = str2double(event.Value);            
        end

        % Value changed function: SpreadingDropDown
        function SpreadingDropDownValueChanged(app, event)
            getEbN0(app)
        end

        % Value changed function: ModulationDropDown
        function ModulationDropDownValueChanged(app, event)
            getEbN0(app)
        end

        % Value changed function: CodeRateDropDown
        function CodeRateDropDownValueChanged(app, event)
            getEbN0(app)
        end

        % Value changed function: ChipRateMchipssDropDown
        function ChipRateMchipssDropDownValueChanged(app, event)
            getEbN0(app)
        end

        % Value changing function: UplinkFrequencyGHzSlider
        function UplinkFrequencyGHzSliderValueChanging(app, event)
            app.UplinkFreq.Value = num2str(round(event.Value,2));            
        end

        % Value changing function: DownlinkFrequencyGHzSlider
        function DownlinkFrequencyGHzSliderValueChanging(app, event)
            app.DownlinkFreq.Value = num2str(round(event.Value,2));            
        end

        % Value changing function: LNBTempKEditField
        function LNBTempKEditFieldValueChanging(app, event)
            app.SatLNB = str2double(event.Value);            
        end

        % Value changing function: FeederLossdBEditField
        function FeederLossdBEditFieldValueChanging(app, event)
            app.SatFeed = str2double(event.Value);
        end

        % Value changing function: SatelliteHPACompressiondBEditField
        function SatelliteHPACompressiondBEditFieldValueChanging(app, event)
            app.HPAComp = str2double(event.Value);            
        end

        % Value changing function: AntennaApertureEfficiencyEditField
        function AntennaApertureEfficiencyEditFieldValueChanging(app, event)
            app.antapef = str2double(event.Value);            
        end

        % Value changing function: PointingLossdBEditField
        function PointingLossdBEditFieldValueChanging(app, event)
            app.PointLoss = str2double(event.Value);            
        end

        % Value changing function: AtmosphericLossdBEditField
        function AtmosphericLossdBEditFieldValueChanging(app, event)
            app.AtmosLoss = str2double(event.Value);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GeostationarySatelliteLinkBudgetToolPanel = uipanel(app.UIFigure);
            app.GeostationarySatelliteLinkBudgetToolPanel.Title = 'Geostationary Satellite Link Budget Tool';
            app.TabGroup = uitabgroup(app.GeostationarySatelliteLinkBudgetToolPanel);
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input';
            app.HubParametersPanel = uipanel(app.InputTab);
            app.HubParametersPanel.Title = 'Hub Parameters';
            app.LocationLabel = uilabel(app.HubParametersPanel);
            app.LocationLabel.Text = 'Location';
            app.HubLocEditField = uieditfield(app.HubParametersPanel, 'text');
            app.HubLocEditField.ValueChangingFcn = createCallbackFcn(app, @HubLocEditFieldValueChanging, true);
            app.HubSearchButton = uibutton(app.HubParametersPanel, 'push');
            app.HubSearchButton.ButtonPushedFcn = createCallbackFcn(app, @HubSearchButtonPushed, true);
            app.HubSearchButton.Text = 'Search';
            app.LatitudeEditFieldLabel = uilabel(app.HubParametersPanel);
            app.LatitudeEditFieldLabel.Text = 'Latitude';
            app.HubLatitudeEditField = uieditfield(app.HubParametersPanel, 'text');
            app.HubLatitudeEditField.ValueChangingFcn = createCallbackFcn(app, @HubLatitudeEditFieldValueChanging, true);
            app.LongitudeEditFieldLabel = uilabel(app.HubParametersPanel);
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.HubLongitudeEditField = uieditfield(app.HubParametersPanel, 'text');
            app.HubLongitudeEditField.ValueChangingFcn = createCallbackFcn(app, @HubLongitudeEditFieldValueChanging, true);
            app.AntennaDiametermEditFieldLabel_3 = uilabel(app.HubParametersPanel);
            app.AntennaDiametermEditFieldLabel_3.Text = {'Antenna'; 'Diameter m'};
            app.HubAntennaDiametermEditField = uieditfield(app.HubParametersPanel, 'text');
            app.HubAntennaDiametermEditField.ValueChangingFcn = createCallbackFcn(app, @HubAntennaDiametermEditFieldValueChanging, true);
            app.PowerWEditFieldLabel_2 = uilabel(app.HubParametersPanel);
            app.PowerWEditFieldLabel_2.Text = 'Power W';
            app.HubPowerWEditField = uieditfield(app.HubParametersPanel, 'text');
            app.HubPowerWEditField.ValueChangingFcn = createCallbackFcn(app, @HubPowerWEditFieldValueChanging, true);
            app.stVsatParametersPanel = uipanel(app.InputTab);
            app.stVsatParametersPanel.Title = '1st Vsat Parameters';
            app.VsatSearchButton = uibutton(app.stVsatParametersPanel, 'push');
            app.VsatSearchButton.ButtonPushedFcn = createCallbackFcn(app, @VsatSearchButtonPushed, true);
            app.VsatSearchButton.Text = 'Search';
            app.LocationLabel_2 = uilabel(app.stVsatParametersPanel);
            app.LocationLabel_2.Text = 'Location';
            app.VsatLocEditField = uieditfield(app.stVsatParametersPanel, 'text');
            app.VsatLocEditField.ValueChangingFcn = createCallbackFcn(app, @VsatLocEditFieldValueChanging, true);
            app.LatitudeEditFieldLabel_2 = uilabel(app.stVsatParametersPanel);
            app.LatitudeEditFieldLabel_2.Text = 'Latitude';
            app.VsatLatitudeEditField = uieditfield(app.stVsatParametersPanel, 'text');
            app.VsatLatitudeEditField.ValueChangingFcn = createCallbackFcn(app, @VsatLatitudeEditFieldValueChanging, true);
            app.LongitudeEditFieldLabel_2 = uilabel(app.stVsatParametersPanel);
            app.LongitudeEditFieldLabel_2.Text = 'Longitude';
            app.VsatLongitudeEditField = uieditfield(app.stVsatParametersPanel, 'text');
            app.VsatLongitudeEditField.ValueChangingFcn = createCallbackFcn(app, @VsatLongitudeEditFieldValueChanging, true);
            app.AntennaDiametermEditField_2Label = uilabel(app.stVsatParametersPanel);
            app.AntennaDiametermEditField_2Label.Text = {'Antenna'; 'Diameter m'};
            app.VsatAntennaDiametermEditField = uieditfield(app.stVsatParametersPanel, 'text');
            app.VsatAntennaDiametermEditField.ValueChangingFcn = createCallbackFcn(app, @VsatAntennaDiametermEditFieldValueChanging, true);
            app.PowerWEditFieldLabel_3 = uilabel(app.stVsatParametersPanel);
            app.PowerWEditFieldLabel_3.Text = 'Power W';
            app.VsatPowerWEditField = uieditfield(app.stVsatParametersPanel, 'text');
            app.VsatPowerWEditField.ValueChangingFcn = createCallbackFcn(app, @VsatPowerWEditFieldValueChanging, true);
            app.SatelliteParametersPanel = uipanel(app.InputTab);
            app.SatelliteParametersPanel.Title = 'Satellite Parameters';
            app.DateEditFieldLabel = uilabel(app.SatelliteParametersPanel);
            app.DateEditFieldLabel.Text = 'Date';
            app.SatDateEditField = uieditfield(app.SatelliteParametersPanel, 'text');
            app.SatDateEditField.Editable = 'off';
            app.NameDropDownLabel = uilabel(app.SatelliteParametersPanel);
            app.NameDropDownLabel.Text = 'Name';
            app.SatNameDropDown = uidropdown(app.SatelliteParametersPanel);
            app.SatNameDropDown.Editable = 'on';
            app.SatNameDropDown.ValueChangedFcn = createCallbackFcn(app, @SatNameDropDownValueChanged, true);
            app.LongitudeEditFieldLabel_3 = uilabel(app.SatelliteParametersPanel);
            app.LongitudeEditFieldLabel_3.Text = 'Longitude';
            app.SatLongitudeEditField = uieditfield(app.SatelliteParametersPanel, 'text');
            app.SatLongitudeEditField.Editable = 'off';
            app.BandDropDownLabel = uilabel(app.SatelliteParametersPanel);
            app.BandDropDownLabel.Text = 'Band';
            app.BandDropDown = uidropdown(app.SatelliteParametersPanel);
            app.BandDropDown.Items = {'L-Band', 'S-Band', 'C-Band', 'X-Band', 'Ku-Band', 'Ka-Band'};
            app.BandDropDown.ValueChangedFcn = createCallbackFcn(app, @BandDropDownValueChanged, true);
            app.BandDropDown.Value = 'L-Band';
            app.NoiseTempKLabel = uilabel(app.SatelliteParametersPanel);
            app.NoiseTempKLabel.Text = {'Antenna Noise'; 'Temp K'};
            app.SatTempKEditField = uieditfield(app.SatelliteParametersPanel, 'text');
            app.SatTempKEditField.ValueChangingFcn = createCallbackFcn(app, @SatTempKEditFieldValueChanging, true);
            app.GaindBiLabel = uilabel(app.SatelliteParametersPanel);
            app.GaindBiLabel.Text = 'Gain dBi';
            app.SatGaindBEditField = uieditfield(app.SatelliteParametersPanel, 'text');
            app.SatGaindBEditField.ValueChangingFcn = createCallbackFcn(app, @SatGaindBEditFieldValueChanging, true);
            app.PowerWEditFieldLabel = uilabel(app.SatelliteParametersPanel);
            app.PowerWEditFieldLabel.Text = 'Power W';
            app.SatPowerWEditField = uieditfield(app.SatelliteParametersPanel, 'text');
            app.SatPowerWEditField.ValueChangingFcn = createCallbackFcn(app, @SatPowerWEditFieldValueChanging, true);
            app.ChannelBandwithMHzEditFieldLabel = uilabel(app.SatelliteParametersPanel);
            app.ChannelBandwithMHzEditFieldLabel.Text = {'Channel'; 'Bandwith '; 'MHz'};
            app.ChannelBandwithMHzEditField = uieditfield(app.SatelliteParametersPanel, 'text');
            app.ChannelBandwithMHzEditField.ValueChangingFcn = createCallbackFcn(app, @ChannelBandwithMHzEditFieldValueChanging, true);
            app.TransponderBandwithMHzEditFieldLabel = uilabel(app.SatelliteParametersPanel);
            app.TransponderBandwithMHzEditFieldLabel.Text = {'Transponder'; 'Bandwith '; 'MHz'};
            app.TransponderBandwithMHzEditField = uieditfield(app.SatelliteParametersPanel, 'text');
            app.TransponderBandwithMHzEditField.ValueChangingFcn = createCallbackFcn(app, @TransponderBandwithMHzEditFieldValueChanging, true);
            app.AntennaDiametermEditFieldLabel_4 = uilabel(app.SatelliteParametersPanel);
            app.AntennaDiametermEditFieldLabel_4.Text = {'Antenna'; 'Diameter m'};
            app.SatAntennaDiametermEditField = uieditfield(app.SatelliteParametersPanel, 'text');
            app.SatAntennaDiametermEditField.ValueChangingFcn = createCallbackFcn(app, @SatAntennaDiametermEditFieldValueChanging, true);
            app.NoiseTempKLabel_2 = uilabel(app.SatelliteParametersPanel);
            app.NoiseTempKLabel_2.Text = {'LNB Noise'; 'Temp K'};
            app.LNBTempKEditField = uieditfield(app.SatelliteParametersPanel, 'text');
            app.LNBTempKEditField.ValueChangingFcn = createCallbackFcn(app, @LNBTempKEditFieldValueChanging, true);
            app.FeederLossdBLabel = uilabel(app.SatelliteParametersPanel);
            app.FeederLossdBLabel.Text = {'Feeder Loss'; 'dB'};
            app.FeederLossdBEditField = uieditfield(app.SatelliteParametersPanel, 'text');
            app.FeederLossdBEditField.ValueChangingFcn = createCallbackFcn(app, @FeederLossdBEditFieldValueChanging, true);
            app.EIRPdBLabel_4 = uilabel(app.SatelliteParametersPanel);
            app.EIRPdBLabel_4.Text = 'EIRP dB';
            app.SatEIRPdBEditField = uieditfield(app.SatelliteParametersPanel, 'text');
            app.SatEIRPdBEditField.Editable = 'off';
            app.CheckBox = uicheckbox(app.SatelliteParametersPanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.GTdBKLabel_4 = uilabel(app.SatelliteParametersPanel);
            app.GTdBKLabel_4.Text = 'G/T dB/K';
            app.SatGTEditField = uieditfield(app.SatelliteParametersPanel, 'text');
            app.SatGTEditField.Editable = 'off';
            app.TransmissionParametersPanel = uipanel(app.InputTab);
            app.TransmissionParametersPanel.Title = 'Transmission Parameters';
            app.UplinkFrequencyGHzLabel = uilabel(app.TransmissionParametersPanel);
            app.UplinkFrequencyGHzLabel.Text = {'Uplink '; 'Frequency GHz'};
            app.UplinkFrequencyGHzSlider = uislider(app.TransmissionParametersPanel);
            app.UplinkFrequencyGHzSlider.Limits = [1 2];
            app.UplinkFrequencyGHzSlider.ValueChangingFcn = createCallbackFcn(app, @UplinkFrequencyGHzSliderValueChanging, true);
            app.UplinkFrequencyGHzSlider.MinorTicks = [];
            app.UplinkFrequencyGHzSlider.Value = 1;
            app.DownlinkFrequencyGHzLabel = uilabel(app.TransmissionParametersPanel);
            app.DownlinkFrequencyGHzLabel.Text = {'Downlink '; 'Frequency GHz'};
            app.DownlinkFrequencyGHzSlider = uislider(app.TransmissionParametersPanel);
            app.DownlinkFrequencyGHzSlider.Limits = [1 2];
            app.DownlinkFrequencyGHzSlider.ValueChangingFcn = createCallbackFcn(app, @DownlinkFrequencyGHzSliderValueChanging, true);
            app.DownlinkFrequencyGHzSlider.MinorTicks = [];
            app.DownlinkFrequencyGHzSlider.Value = 1;
            app.AccessSchemeDropDownLabel = uilabel(app.TransmissionParametersPanel);
            app.AccessSchemeDropDownLabel.Text = {'Access'; 'Scheme'};
            app.AccessSchemeDropDown = uidropdown(app.TransmissionParametersPanel);
            app.AccessSchemeDropDown.Items = {'SCPC', 'TDMA', 'FDMA'};
            app.AccessSchemeDropDown.Value = 'SCPC';
            app.UplinkFreq = uieditfield(app.TransmissionParametersPanel, 'text');
            app.UplinkFreq.ValueChangedFcn = createCallbackFcn(app, @UplinkFreqValueChanged, true);
            app.DownlinkFreq = uieditfield(app.TransmissionParametersPanel, 'text');
            app.DownlinkFreq.ValueChangedFcn = createCallbackFcn(app, @DownlinkFreqValueChanged, true);
            app.SatelliteHPACompressiondBEditFieldLabel = uilabel(app.TransmissionParametersPanel);
            app.SatelliteHPACompressiondBEditFieldLabel.Text = {'Satellite HPA'; 'Compression dB'};
            app.SatelliteHPACompressiondBEditField = uieditfield(app.TransmissionParametersPanel, 'text');
            app.SatelliteHPACompressiondBEditField.ValueChangingFcn = createCallbackFcn(app, @SatelliteHPACompressiondBEditFieldValueChanging, true);
            app.CalculateButton = uibutton(app.InputTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ndVsatParametersPanel = uipanel(app.InputTab);
            app.ndVsatParametersPanel.Title = '2nd Vsat Parameters';
            app.VsatSearchButton_2 = uibutton(app.ndVsatParametersPanel, 'push');
            app.VsatSearchButton_2.ButtonPushedFcn = createCallbackFcn(app, @VsatSearchButton_2Pushed, true);
            app.VsatSearchButton_2.Text = 'Search';
            app.LocationLabel_3 = uilabel(app.ndVsatParametersPanel);
            app.LocationLabel_3.Text = 'Location';
            app.VsatLocEditField_2 = uieditfield(app.ndVsatParametersPanel, 'text');
            app.VsatLocEditField_2.ValueChangingFcn = createCallbackFcn(app, @VsatLocEditField_2ValueChanging, true);
            app.LatitudeEditFieldLabel_3 = uilabel(app.ndVsatParametersPanel);
            app.LatitudeEditFieldLabel_3.Text = 'Latitude';
            app.VsatLatitudeEditField_2 = uieditfield(app.ndVsatParametersPanel, 'text');
            app.VsatLatitudeEditField_2.ValueChangingFcn = createCallbackFcn(app, @VsatLatitudeEditField_2ValueChanging, true);
            app.LongitudeEditFieldLabel_4 = uilabel(app.ndVsatParametersPanel);
            app.LongitudeEditFieldLabel_4.Text = 'Longitude';
            app.VsatLongitudeEditField_2 = uieditfield(app.ndVsatParametersPanel, 'text');
            app.VsatLongitudeEditField_2.ValueChangingFcn = createCallbackFcn(app, @VsatLongitudeEditField_2ValueChanging, true);
            app.AntennaDiametermEditField_2Label_2 = uilabel(app.ndVsatParametersPanel);
            app.AntennaDiametermEditField_2Label_2.Text = {'Antenna'; 'Diameter m'};
            app.VsatAntennaDiametermEditField_2 = uieditfield(app.ndVsatParametersPanel, 'text');
            app.VsatAntennaDiametermEditField_2.ValueChangingFcn = createCallbackFcn(app, @VsatAntennaDiametermEditField_2ValueChanging, true);
            app.PowerWEditFieldLabel_4 = uilabel(app.ndVsatParametersPanel);
            app.PowerWEditFieldLabel_4.Text = 'Power W';
            app.VsatPowerWEditField_2 = uieditfield(app.ndVsatParametersPanel, 'text');
            app.VsatPowerWEditField_2.ValueChangingFcn = createCallbackFcn(app, @VsatPowerWEditField_2ValueChanging, true);
            app.RainAttenuationPanel = uipanel(app.InputTab);
            app.RainAttenuationPanel.Title = 'Rain Attenuation';
            app.EnableCheckBox = uicheckbox(app.RainAttenuationPanel);
            app.EnableCheckBox.Text = 'Enable';
            app.EnableCheckBox.Value = true;
            app.LinkRainAvailabilityEditFieldLabel = uilabel(app.RainAttenuationPanel);
            app.LinkRainAvailabilityEditFieldLabel.Text = 'Link Rain Availability';
            app.LinkRainAvailabilityEditField = uieditfield(app.RainAttenuationPanel, 'numeric');
            app.LinkRainAvailabilityEditField.Limits = [95 99.99];
            app.LinkRainAvailabilityEditField.Value = 99.95;
            app.AtmosphericLossdBEditFieldLabel = uilabel(app.InputTab);
            app.AtmosphericLossdBEditFieldLabel.Text = {'Atmospheric'; ' Loss dB'};
            app.AtmosphericLossdBEditField = uieditfield(app.InputTab, 'text');
            app.AtmosphericLossdBEditField.ValueChangingFcn = createCallbackFcn(app, @AtmosphericLossdBEditFieldValueChanging, true);
            app.PointingLossdBEditFieldLabel = uilabel(app.InputTab);
            app.PointingLossdBEditFieldLabel.Text = {'Pointing'; ' Loss dB'};
            app.PointingLossdBEditField = uieditfield(app.InputTab, 'text');
            app.PointingLossdBEditField.ValueChangingFcn = createCallbackFcn(app, @PointingLossdBEditFieldValueChanging, true);
            app.AntennaApertureEfficiencyEditFieldLabel = uilabel(app.InputTab);
            app.AntennaApertureEfficiencyEditFieldLabel.Text = {'Antenna'; 'Aperture'; 'Efficiency'};
            app.AntennaApertureEfficiencyEditField = uieditfield(app.InputTab, 'text');
            app.AntennaApertureEfficiencyEditField.ValueChangingFcn = createCallbackFcn(app, @AntennaApertureEfficiencyEditFieldValueChanging, true);
            app.CalculationsTab = uitab(app.TabGroup);
            app.CalculationsTab.Title = 'Calculations';
            app.HubPanel = uipanel(app.CalculationsTab);
            app.HubPanel.Title = 'Hub';
            app.ElevationAngledegEditFieldLabel = uilabel(app.HubPanel);
            app.ElevationAngledegEditFieldLabel.Text = {'Elevation'; 'Angle deg'};
            app.HubElevationAngledegEditField = uieditfield(app.HubPanel, 'text');
            app.HubElevationAngledegEditField.Editable = 'off';
            app.AzimuthAngledegEditFieldLabel = uilabel(app.HubPanel);
            app.AzimuthAngledegEditFieldLabel.Text = {'Azimuth'; 'Angle deg'};
            app.HubAzimuthAngledegEditField = uieditfield(app.HubPanel, 'text');
            app.HubAzimuthAngledegEditField.Editable = 'off';
            app.SlantRangeKmEditFieldLabel = uilabel(app.HubPanel);
            app.SlantRangeKmEditFieldLabel.Text = {'Slant'; 'Range Km'};
            app.HubSlantRangeKmEditField = uieditfield(app.HubPanel, 'text');
            app.HubSlantRangeKmEditField.Editable = 'off';
            app.FreeSpaceLossdBEditFieldLabel = uilabel(app.HubPanel);
            app.FreeSpaceLossdBEditFieldLabel.Text = {'Free Space'; 'Loss dB'};
            app.HubFreeSpaceLossdBEditField = uieditfield(app.HubPanel, 'text');
            app.HubFreeSpaceLossdBEditField.Editable = 'off';
            app.SatelliteVisibilityLabel = uilabel(app.HubPanel);
            app.SatelliteVisibilityLabel.Text = 'Satellite Visibility';
            app.HubSatelliteVisibilityLamp = uilamp(app.HubPanel);
            app.GaindBiEditFieldLabel = uilabel(app.HubPanel);
            app.GaindBiEditFieldLabel.Text = 'Gain dBi';
            app.HubGaindBiEditField = uieditfield(app.HubPanel, 'text');
            app.HubGaindBiEditField.Editable = 'off';
            app.SFDdBWm2Label = uilabel(app.HubPanel);
            app.SFDdBWm2Label.Text = {'SFD'; 'dBW/m^2'};
            app.HubSFDEditField = uieditfield(app.HubPanel, 'text');
            app.HubSFDEditField.Editable = 'off';
            app.EIRPdBLabel = uilabel(app.HubPanel);
            app.EIRPdBLabel.Text = 'EIRP dB';
            app.HubEIRPdBEditField = uieditfield(app.HubPanel, 'text');
            app.HubEIRPdBEditField.Editable = 'off';
            app.SFDdBWm2Label_3 = uilabel(app.HubPanel);
            app.SFDdBWm2Label_3.Text = {'PFD'; 'dBW/m^2'};
            app.HubPFDEditField = uieditfield(app.HubPanel, 'text');
            app.HubPFDEditField.Editable = 'off';
            app.GTdBKLabel = uilabel(app.HubPanel);
            app.GTdBKLabel.Text = 'G/T dB/K';
            app.HubGTEditField = uieditfield(app.HubPanel, 'text');
            app.HubGTEditField.Editable = 'off';
            app.stVsatPanel = uipanel(app.CalculationsTab);
            app.stVsatPanel.Title = '1st Vsat';
            app.ElevationAngledegEditFieldLabel_2 = uilabel(app.stVsatPanel);
            app.ElevationAngledegEditFieldLabel_2.Text = {'Elevation'; 'Angle deg'};
            app.VsatElevationAngledegEditField = uieditfield(app.stVsatPanel, 'text');
            app.VsatElevationAngledegEditField.Editable = 'off';
            app.AzimuthAngledegEditFieldLabel_2 = uilabel(app.stVsatPanel);
            app.AzimuthAngledegEditFieldLabel_2.Text = {'Azimuth'; 'Angle deg'};
            app.VsatAzimuthAngledegEditField = uieditfield(app.stVsatPanel, 'text');
            app.VsatAzimuthAngledegEditField.Editable = 'off';
            app.SlantRangeKmEditFieldLabel_2 = uilabel(app.stVsatPanel);
            app.SlantRangeKmEditFieldLabel_2.Text = {'Slant'; 'Range Km'};
            app.VsatSlantRangeKmEditField = uieditfield(app.stVsatPanel, 'text');
            app.VsatSlantRangeKmEditField.Editable = 'off';
            app.FreeSpaceLossdBEditFieldLabel_2 = uilabel(app.stVsatPanel);
            app.FreeSpaceLossdBEditFieldLabel_2.Text = {'Free Space'; 'Loss dB'};
            app.VsatFreeSpaceLossdBEditField = uieditfield(app.stVsatPanel, 'text');
            app.VsatFreeSpaceLossdBEditField.Editable = 'off';
            app.SatelliteVisibilityLabel_2 = uilabel(app.stVsatPanel);
            app.SatelliteVisibilityLabel_2.Text = 'Satellite Visibility';
            app.VsatSatelliteVisibilityLamp = uilamp(app.stVsatPanel);
            app.GaindBiEditFieldLabel_2 = uilabel(app.stVsatPanel);
            app.GaindBiEditFieldLabel_2.Text = 'Gain dBi';
            app.VsatGaindBiEditField = uieditfield(app.stVsatPanel, 'text');
            app.VsatGaindBiEditField.Editable = 'off';
            app.SFDdBWm2Label_2 = uilabel(app.stVsatPanel);
            app.SFDdBWm2Label_2.Text = {'SFD'; 'dBW/m^2'};
            app.VsatSFDEditField = uieditfield(app.stVsatPanel, 'text');
            app.VsatSFDEditField.Editable = 'off';
            app.EIRPdBLabel_2 = uilabel(app.stVsatPanel);
            app.EIRPdBLabel_2.Text = 'EIRP dB';
            app.VsatEIRPdBEditField = uieditfield(app.stVsatPanel, 'text');
            app.VsatEIRPdBEditField.Editable = 'off';
            app.SFDdBWm2Label_4 = uilabel(app.stVsatPanel);
            app.SFDdBWm2Label_4.Text = {'PFD'; 'dBW/m^2'};
            app.VsatPFDEditField = uieditfield(app.stVsatPanel, 'text');
            app.VsatPFDEditField.Editable = 'off';
            app.GTdBKLabel_2 = uilabel(app.stVsatPanel);
            app.GTdBKLabel_2.Text = 'G/T dB/K';
            app.VsatGTEditField = uieditfield(app.stVsatPanel, 'text');
            app.VsatGTEditField.Editable = 'off';
            app.ndVsatPanel = uipanel(app.CalculationsTab);
            app.ndVsatPanel.Title = '2nd Vsat';
            app.ElevationAngledegEditFieldLabel_3 = uilabel(app.ndVsatPanel);
            app.ElevationAngledegEditFieldLabel_3.Text = {'Elevation'; 'Angle deg'};
            app.VsatElevationAngledegEditField_2 = uieditfield(app.ndVsatPanel, 'text');
            app.VsatElevationAngledegEditField_2.Editable = 'off';
            app.AzimuthAngledegEditFieldLabel_3 = uilabel(app.ndVsatPanel);
            app.AzimuthAngledegEditFieldLabel_3.Text = {'Azimuth'; 'Angle deg'};
            app.VsatAzimuthAngledegEditField_2 = uieditfield(app.ndVsatPanel, 'text');
            app.VsatAzimuthAngledegEditField_2.Editable = 'off';
            app.SlantRangeKmEditFieldLabel_3 = uilabel(app.ndVsatPanel);
            app.SlantRangeKmEditFieldLabel_3.Text = {'Slant'; 'Range Km'};
            app.VsatSlantRangeKmEditField_2 = uieditfield(app.ndVsatPanel, 'text');
            app.VsatSlantRangeKmEditField_2.Editable = 'off';
            app.FreeSpaceLossdBEditFieldLabel_3 = uilabel(app.ndVsatPanel);
            app.FreeSpaceLossdBEditFieldLabel_3.Text = {'Free Space'; 'Loss dB'};
            app.VsatFreeSpaceLossdBEditField_2 = uieditfield(app.ndVsatPanel, 'text');
            app.VsatFreeSpaceLossdBEditField_2.Editable = 'off';
            app.SatelliteVisibilityLabel_3 = uilabel(app.ndVsatPanel);
            app.SatelliteVisibilityLabel_3.Text = 'Satellite Visibility';
            app.VsatSatelliteVisibilityLamp_2 = uilamp(app.ndVsatPanel);
            app.GaindBiEditFieldLabel_3 = uilabel(app.ndVsatPanel);
            app.GaindBiEditFieldLabel_3.Text = 'Gain dBi';
            app.VsatGaindBiEditField_2 = uieditfield(app.ndVsatPanel, 'text');
            app.VsatGaindBiEditField_2.Editable = 'off';
            app.SFDdBWm2Label_5 = uilabel(app.ndVsatPanel);
            app.SFDdBWm2Label_5.Text = {'SFD'; 'dBW/m^2'};
            app.VsatSFDEditField_2 = uieditfield(app.ndVsatPanel, 'text');
            app.VsatSFDEditField_2.Editable = 'off';
            app.EIRPdBLabel_3 = uilabel(app.ndVsatPanel);
            app.EIRPdBLabel_3.Text = 'EIRP dB';
            app.VsatEIRPdBEditField_2 = uieditfield(app.ndVsatPanel, 'text');
            app.VsatEIRPdBEditField_2.Editable = 'off';
            app.SFDdBWm2Label_6 = uilabel(app.ndVsatPanel);
            app.SFDdBWm2Label_6.Text = {'PFD'; 'dBW/m^2'};
            app.VsatPFDEditField_2 = uieditfield(app.ndVsatPanel, 'text');
            app.VsatPFDEditField_2.Editable = 'off';
            app.GTdBKLabel_3 = uilabel(app.ndVsatPanel);
            app.GTdBKLabel_3.Text = 'G/T dB/K';
            app.VsatGTEditField_2 = uieditfield(app.ndVsatPanel, 'text');
            app.VsatGTEditField_2.Editable = 'off';
            app.SatellitePanel = uipanel(app.CalculationsTab);
            app.SatellitePanel.Title = 'Satellite';
            app.SatelliteGTdBKEditFieldLabel = uilabel(app.SatellitePanel);
            app.SatelliteGTdBKEditFieldLabel.Text = {'Satellite'; 'G/T dB/K'};
            app.SatelliteGTdBKEditField = uieditfield(app.SatellitePanel, 'text');
            app.SatelliteGTdBKEditField.Editable = 'off';
            app.SatelliteEIRPdBEditFieldLabel = uilabel(app.SatellitePanel);
            app.SatelliteEIRPdBEditFieldLabel.Text = {'Satellite'; 'EIRP dB'};
            app.SatelliteEIRPdBEditField = uieditfield(app.SatellitePanel, 'text');
            app.SatelliteEIRPdBEditField.Editable = 'off';
            app.SatelliteSFDdBWm2Label = uilabel(app.SatellitePanel);
            app.SatelliteSFDdBWm2Label.Text = {'Satellite SFD'; ' dBW/m^2'};
            app.SatelliteSFDdBWm2EditField = uieditfield(app.SatellitePanel, 'text');
            app.SatelliteSFDdBWm2EditField.Editable = 'off';
            app.SatellitePFDdBWm2EditField_2Label = uilabel(app.SatellitePanel);
            app.SatellitePFDdBWm2EditField_2Label.Text = {'Satellite PFD'; ' dBW/m^2'};
            app.SatellitePFDdBWm2EditField = uieditfield(app.SatellitePanel, 'text');
            app.SatellitePFDdBWm2EditField.Editable = 'off';
            app.Lamp = uilamp(app.SatellitePanel);
            app.LinkBudgetTab = uitab(app.TabGroup);
            app.LinkBudgetTab.Title = 'Link Budget';
            app.LinkBudgetPanel = uipanel(app.LinkBudgetTab);
            app.LinkBudgetPanel.Title = 'Link Budget';
            app.UplinkFWDSNRdBEditFieldLabel = uilabel(app.LinkBudgetPanel);
            app.UplinkFWDSNRdBEditFieldLabel.Text = {'Uplink FWD'; 'SNR dB'};
            app.UplinkFWDSNRdBEditField = uieditfield(app.LinkBudgetPanel, 'text');
            app.UplinkFWDSNRdBEditField.Editable = 'off';
            app.ComplexFWDSNRdBEditFieldLabel = uilabel(app.LinkBudgetPanel);
            app.ComplexFWDSNRdBEditFieldLabel.Text = {'Complex FWD'; 'SNR dB'};
            app.ComplexFWDSNRdBEditField = uieditfield(app.LinkBudgetPanel, 'text');
            app.ComplexFWDSNRdBEditField.Editable = 'off';
            app.UplinkRTNSNRdBEditFieldLabel = uilabel(app.LinkBudgetPanel);
            app.UplinkRTNSNRdBEditFieldLabel.Text = {'Uplink RTN'; 'SNR dB'};
            app.UplinkRTNSNRdBEditField = uieditfield(app.LinkBudgetPanel, 'text');
            app.UplinkRTNSNRdBEditField.Editable = 'off';
            app.ComplexRTNSNRdBEditFieldLabel = uilabel(app.LinkBudgetPanel);
            app.ComplexRTNSNRdBEditFieldLabel.Text = {'Complex RTN'; 'SNR dB'};
            app.ComplexRTNSNRdBEditField = uieditfield(app.LinkBudgetPanel, 'text');
            app.ComplexRTNSNRdBEditField.Editable = 'off';
            app.DownlinkFWDSNRdBEditFieldLabel = uilabel(app.LinkBudgetPanel);
            app.DownlinkFWDSNRdBEditFieldLabel.Text = {'Downlink FWD'; 'SNR dB'};
            app.DownlinkFWDSNRdBEditField = uieditfield(app.LinkBudgetPanel, 'text');
            app.DownlinkFWDSNRdBEditField.Editable = 'off';
            app.DownlinkRTNSNRdBEditFieldLabel = uilabel(app.LinkBudgetPanel);
            app.DownlinkRTNSNRdBEditFieldLabel.Text = {'Downlink RTN'; 'SNR dB'};
            app.DownlinkRTNSNRdBEditField = uieditfield(app.LinkBudgetPanel, 'text');
            app.DownlinkRTNSNRdBEditField.Editable = 'off';
            app.FWDBitrateMbpsEditFieldLabel = uilabel(app.LinkBudgetPanel);
            app.FWDBitrateMbpsEditFieldLabel.Text = {'FWD'; 'Bitrate Mbps'};
            app.FWDBitrateMbpsEditField = uieditfield(app.LinkBudgetPanel, 'text');
            app.FWDBitrateMbpsEditField.Editable = 'off';
            app.RTNBitrateMbpsEditFieldLabel = uilabel(app.LinkBudgetPanel);
            app.RTNBitrateMbpsEditFieldLabel.Text = {'RTN'; 'Bitrate Mbps'};
            app.RTNBitrateMbpsEditField = uieditfield(app.LinkBudgetPanel, 'text');
            app.RTNBitrateMbpsEditField.Editable = 'off';
            app.AvailableFWDEbN0dBEditFieldLabel = uilabel(app.LinkBudgetPanel);
            app.AvailableFWDEbN0dBEditFieldLabel.Text = {'Available FWD'; 'Eb/N0 dB'};
            app.AvailableFWDEbN0dBEditField = uieditfield(app.LinkBudgetPanel, 'text');
            app.AvailableFWDEbN0dBEditField.Editable = 'off';
            app.AvailableRTNEbN0dBEditFieldLabel = uilabel(app.LinkBudgetPanel);
            app.AvailableRTNEbN0dBEditFieldLabel.Text = {'Available RTN'; 'Eb/N0 dB'};
            app.AvailableRTNEbN0dBEditField = uieditfield(app.LinkBudgetPanel, 'text');
            app.AvailableRTNEbN0dBEditField.Editable = 'off';
            app.SpreadingDropDownLabel = uilabel(app.LinkBudgetPanel);
            app.SpreadingDropDownLabel.Text = 'Spreading';
            app.SpreadingDropDown = uidropdown(app.LinkBudgetPanel);
            app.SpreadingDropDown.ValueChangedFcn = createCallbackFcn(app, @SpreadingDropDownValueChanged, true);
            app.RequiredEbN0dBEditFieldLabel = uilabel(app.LinkBudgetPanel);
            app.RequiredEbN0dBEditFieldLabel.Text = {'Required'; 'Eb/N0 dB'};
            app.RequiredEbN0dBEditField = uieditfield(app.LinkBudgetPanel, 'text');
            app.RequiredEbN0dBEditField.Editable = 'off';
            app.FWDLinkMargindBEditFieldLabel = uilabel(app.LinkBudgetPanel);
            app.FWDLinkMargindBEditFieldLabel.Text = {'FWD Link'; 'Margin dB'};
            app.FWDLinkMargindBEditField = uieditfield(app.LinkBudgetPanel, 'text');
            app.FWDLinkMargindBEditField.Editable = 'off';
            app.RTNLinkMargindBEditFieldLabel = uilabel(app.LinkBudgetPanel);
            app.RTNLinkMargindBEditFieldLabel.Text = {'RTN Link'; 'Margin dB'};
            app.RTNLinkMargindBEditField = uieditfield(app.LinkBudgetPanel, 'text');
            app.RTNLinkMargindBEditField.Editable = 'off';
            app.FWDLamp = uilamp(app.LinkBudgetPanel);
            app.RTNLamp = uilamp(app.LinkBudgetPanel);
            app.HubDownlinkFWDSNRdBLabel = uilabel(app.LinkBudgetPanel);
            app.HubDownlinkFWDSNRdBLabel.Text = {'Hub Downlink'; 'FWD SNR dB'};
            app.HubDownlinkFWDSNRdBEditField = uieditfield(app.LinkBudgetPanel, 'text');
            app.HubDownlinkFWDSNRdBEditField.Editable = 'off';
            app.HubDownlinkRTNSNRdBEditFieldLabel = uilabel(app.LinkBudgetPanel);
            app.HubDownlinkRTNSNRdBEditFieldLabel.Text = {'Hub Downlink'; 'RTN SNR dB'};
            app.HubDownlinkRTNSNRdBEditField = uieditfield(app.LinkBudgetPanel, 'text');
            app.HubDownlinkRTNSNRdBEditField.Editable = 'off';
            app.HubUplinkFWDSNRdBEditFieldLabel = uilabel(app.LinkBudgetPanel);
            app.HubUplinkFWDSNRdBEditFieldLabel.Text = {'Hub Uplink'; 'FWD SNR dB'};
            app.HubUplinkFWDSNRdBEditField = uieditfield(app.LinkBudgetPanel, 'text');
            app.HubUplinkFWDSNRdBEditField.Editable = 'off';
            app.HubUplinkRTNSNRdBEditFieldLabel = uilabel(app.LinkBudgetPanel);
            app.HubUplinkRTNSNRdBEditFieldLabel.Text = {'Hub Uplink'; 'RTN SNR dB'};
            app.HubUplinkRTNSNRdBEditField = uieditfield(app.LinkBudgetPanel, 'text');
            app.HubUplinkRTNSNRdBEditField.Editable = 'off';
            app.Image = uiimage(app.LinkBudgetPanel);
            app.NetworkConfigurationLabel = uilabel(app.LinkBudgetPanel);
            app.NetworkConfigurationLabel.Text = 'Network Configuration :';
            app.Label = uilabel(app.LinkBudgetPanel);
            app.Label.Text = '';
            app.AvailableEbN0dBEditFieldLabel = uilabel(app.LinkBudgetPanel);
            app.AvailableEbN0dBEditFieldLabel.Text = {'Available'; 'Eb/N0 dB'};
            app.AvailableEbN0dBEditField = uieditfield(app.LinkBudgetPanel, 'text');
            app.AvailableEbN0dBEditField.Editable = 'off';
            app.ComplexLinkMargindBEditFieldLabel = uilabel(app.LinkBudgetPanel);
            app.ComplexLinkMargindBEditFieldLabel.Text = {'Complex Link'; 'Margin dB'};
            app.ComplexLinkMargindBEditField = uieditfield(app.LinkBudgetPanel, 'text');
            app.ComplexLinkMargindBEditField.Editable = 'off';
            app.ComplexSNRdBEditField_2Label = uilabel(app.LinkBudgetPanel);
            app.ComplexSNRdBEditField_2Label.Text = {'Complex'; 'SNR dB'};
            app.ComplexSNRdBEditField = uieditfield(app.LinkBudgetPanel, 'text');
            app.ComplexSNRdBEditField.Editable = 'off';
            app.ModulationDropDownLabel = uilabel(app.LinkBudgetPanel);
            app.ModulationDropDownLabel.Text = 'Modulation';
            app.ModulationDropDown = uidropdown(app.LinkBudgetPanel);
            app.ModulationDropDown.ValueChangedFcn = createCallbackFcn(app, @ModulationDropDownValueChanged, true);
            app.CodeRateDropDownLabel = uilabel(app.LinkBudgetPanel);
            app.CodeRateDropDownLabel.Text = 'Code Rate';
            app.CodeRateDropDown = uidropdown(app.LinkBudgetPanel);
            app.CodeRateDropDown.ValueChangedFcn = createCallbackFcn(app, @CodeRateDropDownValueChanged, true);
            app.ChipRateMchipssDropDownLabel = uilabel(app.LinkBudgetPanel);
            app.ChipRateMchipssDropDownLabel.Text = {'Chip Rate'; 'Mchips/s'};
            app.ChipRateMchipssDropDown = uidropdown(app.LinkBudgetPanel);
            app.ChipRateMchipssDropDown.ValueChangedFcn = createCallbackFcn(app, @ChipRateMchipssDropDownValueChanged, true);
            app.RainFadeAttenuationPanel = uipanel(app.LinkBudgetPanel);
            app.RainFadeAttenuationPanel.Title = 'Rain Fade Attenuation';
            app.HubUplinkAttenuationdBEditFieldLabel = uilabel(app.RainFadeAttenuationPanel);
            app.HubUplinkAttenuationdBEditFieldLabel.Text = {'Hub Uplink'; 'Attenuation dB'};
            app.HubUplinkAttenuationdBEditField = uieditfield(app.RainFadeAttenuationPanel, 'text');
            app.HubUplinkAttenuationdBEditField.Editable = 'off';
            app.HubDownlinkAttenuationdBEditFieldLabel = uilabel(app.RainFadeAttenuationPanel);
            app.HubDownlinkAttenuationdBEditFieldLabel.Text = {'Hub Downlink'; 'Attenuation dB'};
            app.HubDownlinkAttenuationdBEditField = uieditfield(app.RainFadeAttenuationPanel, 'text');
            app.HubDownlinkAttenuationdBEditField.Editable = 'off';
            app.stVsatUplinkAttenuationdBEditFieldLabel = uilabel(app.RainFadeAttenuationPanel);
            app.stVsatUplinkAttenuationdBEditFieldLabel.Text = {'1st Vsat Uplink'; 'Attenuation dB'};
            app.stVsatUplinkAttenuationdBEditField = uieditfield(app.RainFadeAttenuationPanel, 'text');
            app.stVsatUplinkAttenuationdBEditField.Editable = 'off';
            app.stVsatDownlinkAttenuationdBEditFieldLabel = uilabel(app.RainFadeAttenuationPanel);
            app.stVsatDownlinkAttenuationdBEditFieldLabel.Text = {'1st Vsat Downlink'; 'Attenuation dB'};
            app.stVsatDownlinkAttenuationdBEditField = uieditfield(app.RainFadeAttenuationPanel, 'text');
            app.stVsatDownlinkAttenuationdBEditField.Editable = 'off';
            app.ndVsatUplinkAttenuationdBEditFieldLabel = uilabel(app.RainFadeAttenuationPanel);
            app.ndVsatUplinkAttenuationdBEditFieldLabel.Text = {'2nd Vsat Uplink'; 'Attenuation dB'};
            app.ndVsatUplinkAttenuationdBEditField = uieditfield(app.RainFadeAttenuationPanel, 'text');
            app.ndVsatUplinkAttenuationdBEditField.Editable = 'off';
            app.ndVsatDownlinkAttenuationdBEditFieldLabel = uilabel(app.RainFadeAttenuationPanel);
            app.ndVsatDownlinkAttenuationdBEditFieldLabel.Text = {'2nd Vsat Downlink'; 'Attenuation dB'};
            app.ndVsatDownlinkAttenuationdBEditField = uieditfield(app.RainFadeAttenuationPanel, 'text');
            app.ndVsatDownlinkAttenuationdBEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Link_Budget

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end